classdef FFCProcessing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        QualityAssurancePanel          matlab.ui.container.Panel
        AllButton                      matlab.ui.control.Button
        THKButton                      matlab.ui.control.Button
        SNRButton                      matlab.ui.control.Button
        UniformityButton               matlab.ui.control.Button
        GeometricAccButton             matlab.ui.control.Button
        MultichannelDropDown           matlab.ui.control.ListBox
        ExportOptionsPanel             matlab.ui.container.Panel
        CreateFigurePanel              matlab.ui.container.Panel
        ImageButton                    matlab.ui.control.Button
        kspaceButton                   matlab.ui.control.Button
        ExportscanparameterstoworkspaceButton  matlab.ui.control.Button
        ExportstackedimagesButton      matlab.ui.control.Button
        ExportallimagesButton          matlab.ui.control.Button
        ExportscantoworkspaceButton    matlab.ui.control.Button
        Panel_2                        matlab.ui.container.Panel
        BackgroundselectionButton      matlab.ui.control.StateButton
        ReprocessingButton             matlab.ui.control.StateButton
        LoadDataSetButton              matlab.ui.control.Button
        RelaxometryPanel               matlab.ui.container.Panel
        T2Button                       matlab.ui.control.Button
        ColourAxisLowerLimitEditField  matlab.ui.control.NumericEditField
        ColourAxisLowerLimitEditFieldLabel  matlab.ui.control.Label
        ColourAxisUpperLimitEditField  matlab.ui.control.NumericEditField
        ColourAxisUpperLimitEditFieldLabel  matlab.ui.control.Label
        DisplayingasT1Button           matlab.ui.control.StateButton
        LogarithmicMapsButton          matlab.ui.control.StateButton
        CheckfitsButton                matlab.ui.control.StateButton
        GenerateROIDispersionCurveButton  matlab.ui.control.Button
        GenerateRelaxationMapsButton   matlab.ui.control.Button
        ScanListPanel                  matlab.ui.container.Panel
        ScanList                       matlab.ui.control.ListBox
        DisplayOptionsPanel            matlab.ui.container.Panel
        IndividualNormalisationButton  matlab.ui.control.StateButton
        FlipVerticallyButton           matlab.ui.control.Button
        FlipHorizontallyButton         matlab.ui.control.Button
        RotateImageButton              matlab.ui.control.Button
        WindowWidthSlider              matlab.ui.control.Slider
        WindowWidthSliderLabel         matlab.ui.control.Label
        WindowLevelSlider              matlab.ui.control.Slider
        WindowLevelSliderLabel         matlab.ui.control.Label
        CurrentImagePanel              matlab.ui.container.Panel
        RegisterImagesButton           matlab.ui.control.Button
        ImageTypeDropDown              matlab.ui.control.DropDown
        ImageTypeDropDownLabel         matlab.ui.control.Label
        SliceDropDown                  matlab.ui.control.DropDown
        SliceDropDownLabel             matlab.ui.control.Label
        EvolutionTimeDropDown          matlab.ui.control.DropDown
        EvolutionTimeDropDownLabel     matlab.ui.control.Label
        EvolutionFieldDropDown         matlab.ui.control.DropDown
        EvolutionFieldDropDownLabel    matlab.ui.control.Label
        panel_recon                    matlab.ui.container.Panel
        BiasFieldDropDown              matlab.ui.control.DropDown
        BiasFieldDropDownLabel         matlab.ui.control.Label
        CombinationDropDown            matlab.ui.control.DropDown
        CombinationDropDownLabel       matlab.ui.control.Label
        RebuildImagesButton            matlab.ui.control.Button
        FilterDropDown                 matlab.ui.control.DropDown
        FilterDropDownLabel            matlab.ui.control.Label
        WindowSizeEditField            matlab.ui.control.NumericEditField
        WindowSizeEditFieldLabel       matlab.ui.control.Label
        WindowDropDown                 matlab.ui.control.DropDown
        WindowDropDownLabel            matlab.ui.control.Label
        FFTDropDown                    matlab.ui.control.DropDown
        SizeofFFTDropDownLabel         matlab.ui.control.Label
        axis_coordinates               matlab.ui.control.UIAxes
        axis_image                     matlab.ui.control.UIAxes
        axis_kspace                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        currentscan % Currently selected scan
        scan_data %actual processed scan data
        currentmap
        mask
        
    end
    
    methods (Access = private)
        
        function   activeimage(app)
            app.currentscan = app.ScanList.Value;
            if isempty(app.scan_data{app.currentscan}.rot_theta)
                app.scan_data{app.currentscan}.rot_theta = 0;
            end

            if ~isfield(app.scan_data{app.currentscan}, 'geomT') || isempty(app.scan_data{app.currentscan}.geomT)
              app.scan_data{app.currentscan}.geomT = eye(3);
            end

            %generate string lists with appropriate evolution fields, times etc
            fft_list = cellfun(@num2str,{app.scan_data{app.currentscan}.samples,app.scan_data{app.currentscan}.samples*2,app.scan_data{app.currentscan}.samples*4},'UniformOutput',false);
            bevol_list = sprintfc('%g',round(app.scan_data{app.currentscan}.fieldpoints,2));
            tevol_list = sprintfc('%g',round(app.scan_data{app.currentscan}.timepoints(1,:),2));
            
            
            %populate the GUI with the lists
            app.FFTDropDown.Items = fft_list;
            app.EvolutionFieldDropDown.Items = bevol_list;
            app.EvolutionTimeDropDown.Items = tevol_list;
            app.SliceDropDown.Items = sprintfc('%i',1:app.scan_data{app.currentscan}.slices);
            
            %allows use of indexing
            app.EvolutionFieldDropDown.ItemsData = 1:numel(app.EvolutionFieldDropDown.Items); %allows us to get indexes
            app.EvolutionTimeDropDown.ItemsData = 1:numel(app.EvolutionTimeDropDown.Items);
            app.SliceDropDown.ItemsData = 1:app.scan_data{app.currentscan}.slices;
            app.MultichannelDropDown.Items = string(1:app.scan_data{app.currentscan}.n_receivers);
            app.MultichannelDropDown.ItemsData = 1:numel(app.MultichannelDropDown.Items);
            
            if length(app.MultichannelDropDown.ItemsData) <3
                app.SliceDropDown.Enable = 0;
            else
                app.SliceDropDown.Enable = 1;
            end 
            
            
            if length(app.SliceDropDown.ItemsData) ==1
                app.SliceDropDown.Enable = 0;
            else
                app.SliceDropDown.Enable = 1;
            end
            
            if length(app.EvolutionTimeDropDown.ItemsData) ==1
                app.EvolutionTimeDropDown.Enable = 0;
                app.GenerateROIDispersionCurveButton.Enable=0;
                app.GenerateRelaxationMapsButton.Enable =0;
                
                
            else
                app.EvolutionTimeDropDown.Enable = 1;
                app.GenerateROIDispersionCurveButton.Enable=1;
                app.GenerateRelaxationMapsButton.Enable =1;
                
            end
            
            if length(app.EvolutionFieldDropDown.ItemsData) ==1
                app.EvolutionFieldDropDown.Enable = 0;
            else
                app.EvolutionFieldDropDown.Enable = 1;
            end
            
            app.FFTDropDown.Value =  num2str(app.scan_data{app.currentscan}.fft_size);
            app.WindowSizeEditField.Value = app.scan_data{app.currentscan}.window_size;
            
            if strcmp(app.scan_data{app.currentscan}.window_function, 'none')
                app.scan_data{app.currentscan}.window_function = 'None';
            end
            app.WindowDropDown.Value = app.scan_data{app.currentscan}.window_function;
            
            if strcmp(app.scan_data{app.currentscan}.denoise_filter, 'none')
                app.scan_data{app.currentscan}.denoise_filter = 'None';
            end
            if isempty(app.scan_data{app.currentscan}.denoise_params)
                app.scan_data{app.currentscan}.denoise_params = 1;
            end
            
            if isempty(app.scan_data{app.currentscan}.multichannel_recon)
                app.scan_data{app.currentscan}.multichannel_recon = '1';
            end

            if isempty(app.scan_data{app.currentscan}.biascorrect)
                app.scan_data{app.currentscan}.biascorrect = 'None';
            end

            if isempty(app.scan_data{app.currentscan}.combination)
                app.scan_data{app.currentscan}.combination = 'None';
            end
            app.FilterDropDown.Value = app.scan_data{app.currentscan}.denoise_filter;
            
            
             app.SliceDropDown.Value = 1;
            app.EvolutionFieldDropDown.Value =1;
            app.EvolutionTimeDropDown.Value =1;
            nCh = app.scan_data{app.currentscan}.n_receivers;
            app.MultichannelDropDown.Items     = string(1:nCh);
            app.MultichannelDropDown.ItemsData = 1:nCh;
            app.MultichannelDropDown.Value     = 1:nCh;
            app.scan_data{app.currentscan}.multichannel_recon = app.MultichannelDropDown.Value;
            %kspace to image space with selected recon parameters
            app.scan_data{app.currentscan} = buildimages(app.scan_data{app.currentscan});
            
         img0 = app.scan_data{app.currentscan}.magimage(:,:,1,1,1);
autoWindowFromImage(app, img0);
            
            drawimages(app)


        end
        
       function autoWindowFromImage(app, img)
    x = double(img(:));
    x = x(isfinite(x));

    if isempty(x)
        return;
    end

    pLo = prctile(x, 1);
    pHi = prctile(x, 99);

    if pHi <= pLo
        pLo = min(x);
        pHi = max(x);
        if pHi <= pLo
            pHi = pLo + 1;
        end
    end

    WL = (pLo + pHi)/2;
    WW = max(pHi - pLo, eps);

    % Set slider limits wide enough that you can “push” past auto-window
    app.WindowLevelSlider.Limits = [pLo - 0.5*WW, pHi + 0.5*WW];  % WL range
    app.WindowWidthSlider.Limits   = [WW*0.01, WW*5];               % WW range

    app.WindowLevelSlider.Value = WL;
    app.WindowWidthSlider.Value   = WW;
end 
        
        
        function drawimages(app)
            axis(app.axis_image,'image'); %forces image axis to rescale to the image matrix size
            axis(app.axis_kspace,'image');
            caxis(app.axis_image,'auto'); %initially use automatic colourscale, we can change to defined limits later if desired
            
            %get display params from the sliders
            WL = app.WindowLevelSlider.Value;  % window level
            WW = app.WindowWidthSlider.Value;    % window width
            
            %get the currently selected field/time/slice
            disp_slice = app.SliceDropDown.Value;
            disp_field = app.EvolutionFieldDropDown.Value;
            disp_time = app.EvolutionTimeDropDown.Value;
            
            absolute_scale = app.IndividualNormalisationButton.Value; %how will the image intensity be scaled?
            
            ImageType = app.ImageTypeDropDown.Value; %magnitude, phase or map
            
            displayedkspace = log(1+(app.scan_data{app.currentscan}.magkspace(:,:,disp_slice,disp_time,disp_field))); %kspace is easier to visualise on a log scale
            
            switch ImageType
                case "Magnitude"
                    displayedimage = double(app.scan_data{app.currentscan}.magimage(:,:,disp_slice,disp_time,disp_field));

    % PACS windowing: map to [0,1] using WL/WW
    low  = WL - WW/2;
    high = WL + WW/2;
    if high <= low
        high = low + eps;
    end
    displayedimage = (displayedimage - low) ./ (high - low);
    displayedimage = min(max(displayedimage, 0), 1);

    colormap(app.axis_image, gray);
    colormap(app.axis_kspace, gray);
                    
                case "Phase"
                    displayedimage = double(app.scan_data{app.currentscan}.phaseimage(:,:,disp_slice,disp_time,disp_field));
    displayedimage = (displayedimage - (-pi)) / (2*pi);
    displayedimage = min(max(displayedimage, 0), 1);
    colormap(app.axis_image, gray);
    colormap(app.axis_kspace, gray);
                    
                case "Relaxometry Map"
                    app.scan_data{app.currentscan}.R1T1 = app.DisplayingasT1Button.Value; %T1 or R1
                    caxis(app.axis_image,[app.ColourAxisLowerLimitEditField.Value,app.ColourAxisUpperLimitEditField.Value]); %get the limits from the GUI
                    colormap(app.axis_kspace,'gray'); %keep kspace grayscale
                    colormap(app.axis_image,'jet');
                    if app.scan_data{app.currentscan}.R1T1 == 0
                        if app.LogarithmicMapsButton.Value ==0
                            displayedimage = app.scan_data{app.currentscan}.T1Maps(:,:,disp_slice,disp_field);
                        else
                            displayedimage = log(app.scan_data{app.currentscan}.T1Maps(:,:,disp_slice,disp_field));
                        end
                    else
                        if app.LogarithmicMapsButton.Value ==0
                            displayedimage = app.scan_data{app.currentscan}.R1Maps(:,:,disp_slice,disp_field);
                        else
                            displayedimage = log(app.scan_data{app.currentscan}.R1Maps(:,:,disp_slice,disp_field));
                        end
                    end
            end
            
            if absolute_scale  && ImageType == "Magnitude" %only do imshow scaling on mag images - doesn't make sense for phase and relaxation maps have their own scaling
                max_level = max(app.scan_data{app.currentscan}.magimage(:));
                imshow(displayedimage,[0 255],'Parent',app.axis_image); %0.2 * max is a reasonable guess but should revisit this
                imagesc(app.axis_image,displayedimage);
            else
                
                imagesc(app.axis_kspace, displayedkspace);
                imagesc(app.axis_image,displayedimage);
            end
            
            
% ---- Orientation compass ----

% ---- Orientation compass using Image_Orientation_subject ----
ios = [];

    ios = app.scan_data{app.currentscan}.orientation;


cla(app.axis_coordinates);
hold(app.axis_coordinates,'on');
axis(app.axis_coordinates,[-1.2 1.2 -1.2 1.2 -1.2 1.2]);
axis(app.axis_coordinates,'off');
grid(app.axis_coordinates,'off');

% If we don't have a valid 1x6 (or 6x1) orientation, just draw a default triad
if isempty(ios) || numel(ios) ~= 6
    % Default: L/R along X, P/A along Y, H/F along Z in this compass space
    L_img = [ 1; 0; 0];  R_img = -L_img;
    P_img = [ 0; 1; 0];  A_img = -P_img;
    H_img = [ 0; 0; 1];  F_img = -H_img;
else
    % ios = [rowDir(1:3)  colDir(1:3)] in patient coordinates
    ios = ios(:);            % force to 6x1 column
    ios = ios(1:6);          % just in case

    R_pat = ios(1:3);        % 3x1 column (row direction in patient space)
    C_pat = ios(4:6);        % 3x1 column (column direction in patient space)

    % Ensure unit vectors
    R_pat = R_pat ./ norm(R_pat);
    C_pat = C_pat ./ norm(C_pat);
    N_pat = cross(R_pat, C_pat);      % slice normal in patient coords
    N_pat = N_pat ./ norm(N_pat);
R_pat_disp = C_pat;
C_pat_disp = R_pat;
    % Rotation matrix: image -> patient (columns are basis vectors)
    M = [R_pat_disp, C_pat_disp, N_pat];      % 3x3

    % Inverse (patient -> image). For rotation, inverse is transpose.
    Minv = M.';                       % 3x3

    % Patient basis (DICOM): +X=L, +Y=P, +Z=H
    eL_pat = [ -1; 0; 0];
    eP_pat = [ 0; 1; 0];
    eH_pat = [ 0; 0; -1];

    % Directions of patient axes in image-coordinate compass space
    uLR = Minv * eL_pat;              % direction of L
    uPA = Minv * eP_pat;              % direction of P
    uHF = Minv * eH_pat;              % direction of H

    % Opposite directions
    L_img =  uLR;   R_img = -uLR;
    P_img =  uPA;   A_img = -uPA;
    H_img =  uHF;   F_img = -uHF;
end

% --- Apply accumulated image-plane transform (rotations + flips) ---
    T = app.scan_data{app.currentscan}.geomT;


L_img = T * L_img;
R_img = T * R_img;
P_img = T * P_img;
A_img = T * A_img;
H_img = T * H_img;
F_img = T * F_img;

% Plot the three axes as lines
plot3(app.axis_coordinates, [L_img(1) R_img(1)], ...
                             [L_img(2) R_img(2)], ...
                             [L_img(3) R_img(3)], 'LineWidth', 3); % L–R

plot3(app.axis_coordinates, [P_img(1) A_img(1)], ...
                             [P_img(2) A_img(2)], ...
                             [P_img(3) A_img(3)], 'LineWidth', 3); % P–A

plot3(app.axis_coordinates, [F_img(1) H_img(1)], ...
                             [F_img(2) H_img(2)], ...
                             [F_img(3) H_img(3)], 'LineWidth', 3); % F–H

% Label the endpoints slightly beyond the tips
scale = 1.15;
text(app.axis_coordinates, L_img(1)*scale, L_img(2)*scale, L_img(3)*scale, ...
     'L', 'FontWeight','Bold','FontSize',14);
text(app.axis_coordinates, R_img(1)*scale, R_img(2)*scale, R_img(3)*scale, ...
     'R', 'FontWeight','Bold','FontSize',14);

text(app.axis_coordinates, P_img(1)*scale, P_img(2)*scale, P_img(3)*scale, ...
     'P', 'FontWeight','Bold','FontSize',14);
text(app.axis_coordinates, A_img(1)*scale, A_img(2)*scale, A_img(3)*scale, ...
     'A', 'FontWeight','Bold','FontSize',14);

text(app.axis_coordinates, H_img(1)*scale, H_img(2)*scale, H_img(3)*scale, ...
     'H', 'FontWeight','Bold','FontSize',14);
text(app.axis_coordinates, F_img(1)*scale, F_img(2)*scale, F_img(3)*scale, ...
     'F', 'FontWeight','Bold','FontSize',14);

hold(app.axis_coordinates,'off');

drawnow


        end
        
        function updateimages(app)  %this method reconstructs the images in case of a change of filter or fft size - more time consuming than a simple redraw
            app.scan_data{app.currentscan}.fft_size = str2double(app.FFTDropDown.Value);
            app.scan_data{app.currentscan}.window_size = app.WindowSizeEditField.Value;
            app.scan_data{app.currentscan}.window_function = app.WindowDropDown.Value;
            app.scan_data{app.currentscan}.denoise_filter = app.FilterDropDown.Value;
            app.scan_data{app.currentscan}.multichannel_recon = app.MultichannelDropDown.Value;
            app.scan_data{app.currentscan}.combination = app.CombinationDropDown.Value;
            app.scan_data{app.currentscan}.biascorrect = app.BiasFieldDropDown.Value;
            app.scan_data{app.currentscan} = buildimages(app.scan_data{app.currentscan});

img0 = app.scan_data{app.currentscan}.magimage(:,:,app.SliceDropDown.Value, ...
                                              app.EvolutionTimeDropDown.Value, ...
                                              app.EvolutionFieldDropDown.Value);
autoWindowFromImage(app, img0);

            drawimages(app)
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: LoadDataSetButton
        function LoadDataSetButtonPushed(app, event)
            file = open_mrifile;
           
            for n=1:length(file)
                file{n} = rename_sequence(file{n});
                sequencelist{n} = file{n}.displayname; %#ok<AGROW>
                try
                    file{n}.backgroundselect = get(app.BackgroundselectionButton,'Value');
                    file{n}.reprocess = get(app.ReprocessingButton,'Value');
                    file{n}.checkfit = get(app.CheckfitsButton,'Value');
                    file{n} = preprocessing(file{n}); %sort out k-space for this sequence
                    file{n}.T1Maps = zeros(file{n}.views,file{n}.samples,file{n}.slices,length(file{n}.fieldpoints));
                    file{n}.R1Maps = zeros(file{n}.views,file{n}.samples,file{n}.slices,length(file{n}.fieldpoints));
                    saveList = file;
                catch ME
                    disp(['Error while processing sequence number ' num2str(n) ':'])
                    disp(ME)
                end
            end
            save('ProcessedImages.mat','saveList'); %after preprocessing we can save the data set so it doesn't have to be done again
            
            app.ScanList.Items = sequencelist;
            app.ScanList.ItemsData =1:numel(app.ScanList.Items); %allows us to get index of selected scan
            app.currentscan = 1; %start on scan 1
            app.scan_data = file; %save the scan to the GUI for easy access
            
            set(findall(app.UIFigure, '-property', 'Enable'), 'Enable', 'on') %enable all the GUI options now that we have a data set to work on. Errors abound if there is no data set loaded and buttons are pushed!

            activeimage(app) %start drawing images
  

% Optional: make sure the figure can take focus
figure(app.UIFigure);
            
        end

        % Value changed function: WindowLevelSlider
        function WindowLevelSliderValueChanged(app, event)
            drawimages(app)
        end

        % Value changed function: WindowWidthSlider
        function WindowWidthSliderValueChanged(app, event)
          drawimages(app)
        end

        % Callback function
        function GammaSliderValueChanged(app, event)
        drawimages(app)
        end

        % Value changed function: ScanList
        function ScanListValueChanged(app, event)
            activeimage(app)
            
        end

        % Value changed function: SliceDropDown
        function SliceDropDownValueChanged(app, event)
            drawimages(app)
        end

        % Value changed function: FFTDropDown
        function FFTDropDownValueChanged(app, event)
            updateimages(app)
        end

        % Value changed function: EvolutionTimeDropDown
        function EvolutionTimeDropDownValueChanged(app, event)
            drawimages(app)
        end

        % Value changed function: EvolutionFieldDropDown
        function EvolutionFieldDropDownValueChanged(app, event)
            disp_field = app.EvolutionFieldDropDown.Value;
            tevol_list = sprintfc('%g',round(app.scan_data{app.currentscan}.timepoints(disp_field,:),2)); %update the evolution times
            app.EvolutionTimeDropDown.Items = tevol_list;
            app.EvolutionTimeDropDown.ItemsData = 1:numel(app.EvolutionTimeDropDown.Items);
            drawimages(app)
        end

        % Value changed function: ImageTypeDropDown
        function ImageTypeDropDownValueChanged(app, event)
            drawimages(app)
        end

        % Value changed function: WindowDropDown
        function WindowDropDownValueChanged(app, event)
            updateimages(app)
        end

        % Value changed function: WindowSizeEditField
        function WindowSizeEditFieldValueChanged(app, event)
            updateimages(app)
        end

        % Button pushed function: GenerateROIDispersionCurveButton
        function GenerateROIDispersionCurveButtonPushed(app, event)

    % Delete previous ROI safely
    try
        if ~isempty(app.mask) && isvalid(app.mask)
            delete(app.mask);
        end
    catch
    end

    % --- Temporarily disable custom mouse handlers so drawfreehand can work ---
    f = app.UIFigure;

    oldDown  = f.WindowButtonDownFcn;
    oldMove  = f.WindowButtonMotionFcn;
    oldUp    = f.WindowButtonUpFcn;

    % Scroll wheel is optional depending on what you added
    oldWheel = [];
    if isprop(f,'WindowScrollWheelFcn')
        oldWheel = f.WindowScrollWheelFcn;
    end

    f.WindowButtonDownFcn = [];
    f.WindowButtonMotionFcn = [];
    f.WindowButtonUpFcn = [];
    if isprop(f,'WindowScrollWheelFcn')
        f.WindowScrollWheelFcn = [];
    end

    % Give focus to the figure (helps interaction)
    figure(f);

    % --- Draw ROI (blocking until completed/double-click) ---
    app.mask = drawfreehand(app.axis_image);

    % --- Restore handlers ---
    f.WindowButtonDownFcn = oldDown;
    f.WindowButtonMotionFcn = oldMove;
    f.WindowButtonUpFcn = oldUp;
    if isprop(f,'WindowScrollWheelFcn')
        f.WindowScrollWheelFcn = oldWheel;
    end

    % If user cancelled / ROI invalid, bail out cleanly
    if isempty(app.mask) || ~isvalid(app.mask)
        return;
    end

    % Continue your pipeline
    app.scan_data{app.currentscan}.mask = createMask(app.mask);
    app.scan_data{app.currentscan}.checkfit = app.CheckfitsButton.Value;
    app.scan_data{app.currentscan}.R1T1 = app.DisplayingasT1Button.Value;
    app.scan_data{app.currentscan}.dispersioncurve = T1dispersion( ...
        app.scan_data{app.currentscan}, app.SliceDropDown.Value);
        end

        % Button pushed function: GenerateRelaxationMapsButton
        function GenerateRelaxationMapsButtonPushed(app, event)
            app.scan_data{app.currentscan}.checkfit = app.CheckfitsButton.Value;
            app.scan_data{app.currentscan}.R1T1 = app.DisplayingasT1Button.Value;
            app.scan_data{app.currentscan} = MapRelaxation(app.scan_data{app.currentscan});
            drawimages(app)
        end

        % Button pushed function: RotateImageButton
        function RotateImageButtonPushed(app, event)
            theta = 90; %rot90 would work here but defining an affine transformation might be useful for future methods like registration/rescaling

ct = cosd(theta);
    st = sind(theta);
    Rz = [ ct  st  0;
          -st  ct  0;
           0   0  1];

    % Accumulate geometric transform: new T = Rz * old T
    
    app.scan_data{app.currentscan}.geomT = Rz * app.scan_data{app.currentscan}.geomT;


            tform_rotation = affine2d([...
                cosd(theta) sind(theta) 0;...
                -sind(theta) cosd(theta) 0; ...
                0 0 1]);
            app.scan_data{app.currentscan}.rot_theta =   mod((app.scan_data{app.currentscan}.rot_theta + 90),360); %not currently used but a future update will allow the undoing of geometric warping so useful to keep track of it
            %             invtheta = -1*app.scan_data{app.currentscan}.rot_theta;
            %             tform_undo_rotation = affine2d([...
            %                 cosd( invtheta) sind( invtheta) 0;...
            %                 -sind( invtheta) cosd( invtheta) 0; ...
            %                 0 0 1]);
            app.scan_data{app.currentscan}.originalcomplexkspace = imwarp(app.scan_data{app.currentscan}.originalcomplexkspace,tform_rotation);
            app.scan_data{app.currentscan}.complexkspace = imwarp(app.scan_data{app.currentscan}.complexkspace,tform_rotation);
            app.scan_data{app.currentscan}.compleximage = imwarp(app.scan_data{app.currentscan}.compleximage,tform_rotation);
            app.scan_data{app.currentscan}.magimage = imwarp(app.scan_data{app.currentscan}.magimage,tform_rotation);
            app.scan_data{app.currentscan}.phaseimage = imwarp( app.scan_data{app.currentscan}.phaseimage,tform_rotation);
            app.scan_data{app.currentscan}.T1Maps = imwarp(app.scan_data{app.currentscan}.T1Maps,tform_rotation);
            app.scan_data{app.currentscan}.R1Maps = imwarp(app.scan_data{app.currentscan}.R1Maps,tform_rotation);
            drawimages(app)
        end

        % Value changed function: FilterDropDown
        function FilterDropDownValueChanged(app, event)

           updateimages(app)
            
        end

        % Callback function
        function FilterControlEditFieldValueChanged(app, event)
            updateimages(app)
            
        end

        % Value changed function: ColourAxisUpperLimitEditField
        function ColourAxisUpperLimitEditFieldValueChanged(app, event)
         %defines the limits of T1 or R1 that are displayed in the
         %relaxation maps
            if app.ColourAxisUpperLimitEditField.Value < app.ColourAxisLowerLimitEditField.Value
                app.ColourAxisUpperLimitEditField.Value = app.ColourAxisLowerLimitEditField.Value + 1; %stops errors when upperbound is less than lowerbound or vice versa
            end
            
            if app.ColourAxisLowerLimitEditField.Value > app.ColourAxisUpperLimitEditField.Value
                app.ColourAxisLowerLimitEditField.Value = app.ColourAxisUpperLimitEditField.Value - 1;
            end
            
            drawimages(app)
        end

        % Value changed function: ColourAxisLowerLimitEditField
        function ColourAxisLowerLimitEditFieldValueChanged(app, event)
            if app.ColourAxisUpperLimitEditField.Value < app.ColourAxisLowerLimitEditField.Value
               app.ColourAxisUpperLimitEditField.Value = app.ColourAxisLowerLimitEditField.Value + 1;
            end
            
            if app.ColourAxisLowerLimitEditField.Value > app.ColourAxisUpperLimitEditField.Value
               app.ColourAxisLowerLimitEditField.Value = app.ColourAxisUpperLimitEditField.Value - 1;
            end
            
            drawimages(app)
        end

        % Value changed function: LogarithmicMapsButton
        function LogarithmicMapsButtonValueChanged(app, event)
            value = app.LogarithmicMapsButton.Value;
            if value
                app.LogarithmicMapsButton.Text = "Log scale Relaxation Maps";
                %try and select sensible limits when log scale selected
                if  app.ColourAxisLowerLimitEditField.Value <1  %log of 0 is inf
                    app.ColourAxisLowerLimitEditField.Value = 1;
                end
                app.ColourAxisLowerLimitEditField.Value =  log(app.ColourAxisLowerLimitEditField.Value);
                app.ColourAxisUpperLimitEditField.Value =  log(app.ColourAxisUpperLimitEditField.Value);
            else
                app.LogarithmicMapsButton.Text = "Linear scale Relaxation Maps";
                app.ColourAxisLowerLimitEditField.Value =  exp(app.ColourAxisLowerLimitEditField.Value);
                app.ColourAxisUpperLimitEditField.Value =  exp(app.ColourAxisUpperLimitEditField.Value);
            end
            drawimages(app)
            
        end

        % Value changed function: DisplayingasT1Button
        function DisplayingasT1ButtonValueChanged(app, event)
            value = app.DisplayingasT1Button.Value;
            if value
                %try and select sensible limits since R1 is in s-1 and T1
                %is in ms
                app.DisplayingasT1Button.Text = "Displaying as R1";
                app.ColourAxisLowerLimitEditField.Value =  app.ColourAxisLowerLimitEditField.Value/100;
                app.ColourAxisUpperLimitEditField.Value =  app.ColourAxisUpperLimitEditField.Value/100;
            else
                app.DisplayingasT1Button.Text = "Displaying as T1";
                app.ColourAxisLowerLimitEditField.Value =  app.ColourAxisLowerLimitEditField.Value*100;
                app.ColourAxisUpperLimitEditField.Value =  app.ColourAxisUpperLimitEditField.Value*100;
            end
            
            drawimages(app)
        end

        % Button pushed function: ExportscantoworkspaceButton
        function ExportscantoworkspaceButtonPushed(app, event)
         file = app.scan_data{app.currentscan};

    try
        rawName = file.displayname;
    catch exception
        rawName = "scan";
    end


    % Convert to char/string safely
    rawName = string(rawName);

    % Make valid MATLAB variable name
    name = matlab.lang.makeValidName(rawName);

 
    existing = evalin('base','who');
    name = matlab.lang.makeUniqueStrings(name, existing);

    assignin('base', name, file);

    % Optional: tell the user what name it used
    uialert(app.UIFigure, "Exported as: " + name, "Export complete");
    
        end

        % Button pushed function: ExportscanparameterstoworkspaceButton
        function ExportscanparameterstoworkspaceButtonPushed(app, event)
            %extract all the relevant scan parameters from the currently
            %selected file and display them in the command window
            file = app.scan_data{app.currentscan};
            disp('--------------------')
            disp(['Experiment name = ',file.name])
            disp(['Sequence name = ',file.sequence])
            disp(['Matrix size = ',num2str(file.samples),' x ',num2str(file.samples)])
            if file.partialkspace == 1
                disp('Partial Fourier = True')
            else
                disp('Partial Fourier = False')
            end
            disp(['Acquisition Bandwidth = ',num2str(file.bandwidth),' KHz'])
            disp(['FOV = ',num2str(file.fov),' mm'])
            disp(['TE = ',num2str(file.TE),' ms'])
            disp(['Flip Angle = ',num2str(file.FlipAngle),' degrees'])
            disp_slice = app.SliceDropDown.Value;
            orientation = file.orientation(:,disp_slice)';
            orientation = num2str(orientation);
            switch orientation
                case '-90   0   0'
                    disp('Orientation = Coronal');
                case '0  0  0'
                    disp('Orientation = Axial');
                otherwise
                    disp(['Orientation = ',orientation]);
            end
            disp(['Slice thickness = ',num2str(file.thk),' mm'])
            disp(['Inplane resolution = = ',num2str(file.resolution_inplane),' mm'])
            disp('Evolution Fields (mT): ')
            disp(num2str(file.fieldpoints,3))
            disp('Evolution Times (ms): ')
            disp(num2str(file.timepoints,3))
            
            
            disp('--------------------')
        end

        % Button pushed function: kspaceButton
        function kspaceButtonPushed(app, event)
            h = figure;
            %get the image as it appears in the axis
            A = getimage(app.axis_kspace);
            cmap = colormap(app.axis_kspace);
            colormap(h,cmap)
            imagesc(A); axis off image
        end

        % Button pushed function: ImageButton
        function ImageButtonPushed(app, event)
            h = figure;
            A = getimage(app.axis_image);
            cmap = colormap(app.axis_image);
            colormap(h,cmap)
            if app.ImageTypeDropDown.Value == "Relaxometry Map"
                imshow(A,[app.ColourAxisLowerLimitEditField.Value,app.ColourAxisUpperLimitEditField.Value]);
                colormap(h,'jet')
            elseif app.IndividualNormalisationButton.Value
                imshow(A,[0 max(app.scan_data{app.currentscan}.magimage(:)*0.2)]);
            else
                imagesc(A); axis off image
            end
            
        end

        % Value changed function: IndividualNormalisationButton
        function IndividualNormalisationButtonValueChanged(app, event)
            value = app.IndividualNormalisationButton.Value;
            if value
                app.IndividualNormalisationButton.Text = "Relative Normalisation";
            else
                app.IndividualNormalisationButton.Text = "Individual Normalisation";
            end
            drawimages(app)
        end

        % Button pushed function: FlipHorizontallyButton
        function FlipHorizontallyButtonPushed(app, event)
            theta = 0;
    tform_rotation = affine2d([...
        -cosd(theta) sind(theta) 0;...
        -sind(theta) cosd(theta) 0; ...
        0 0 1]);

    % Horizontal flip matrix (about vertical axis)
    Sx = [-1 0 0;
           0 1 0;
           0 0 1];


    app.scan_data{app.currentscan}.geomT = Sx * app.scan_data{app.currentscan}.geomT;
            app.scan_data{app.currentscan}.originalcomplexkspace = imwarp(app.scan_data{app.currentscan}.originalcomplexkspace,tform_rotation);
            app.scan_data{app.currentscan}.complexkspace = imwarp(app.scan_data{app.currentscan}.complexkspace,tform_rotation);
            app.scan_data{app.currentscan}.compleximage = imwarp(app.scan_data{app.currentscan}.compleximage,tform_rotation);
            app.scan_data{app.currentscan}.magimage = imwarp(app.scan_data{app.currentscan}.magimage,tform_rotation);
            app.scan_data{app.currentscan}.phaseimage = imwarp( app.scan_data{app.currentscan}.phaseimage,tform_rotation);
            app.scan_data{app.currentscan}.T1Maps = imwarp(app.scan_data{app.currentscan}.T1Maps,tform_rotation);
            app.scan_data{app.currentscan}.R1Maps = imwarp(app.scan_data{app.currentscan}.R1Maps,tform_rotation);
            drawimages(app)
           
        end

        % Button pushed function: FlipVerticallyButton
        function FlipVerticallyButtonPushed(app, event)
            theta = 0;
    tform_rotation = affine2d([...
        cosd(theta) sind(theta) 0;...
        -sind(theta) -cosd(theta) 0; ...
        0 0 1]);

    % Vertical flip matrix (about horizontal axis)
    Sy = [ 1  0 0;
           0 -1 0;
           0  0 1];

    
    app.scan_data{app.currentscan}.geomT = Sy * app.scan_data{app.currentscan}.geomT;
            app.scan_data{app.currentscan}.originalcomplexkspace = imwarp(app.scan_data{app.currentscan}.originalcomplexkspace,tform_rotation);
            app.scan_data{app.currentscan}.complexkspace = imwarp(app.scan_data{app.currentscan}.complexkspace,tform_rotation);
            app.scan_data{app.currentscan}.compleximage = imwarp(app.scan_data{app.currentscan}.compleximage,tform_rotation);
            app.scan_data{app.currentscan}.magimage = imwarp(app.scan_data{app.currentscan}.magimage,tform_rotation);
            app.scan_data{app.currentscan}.phaseimage = imwarp( app.scan_data{app.currentscan}.phaseimage,tform_rotation);
            app.scan_data{app.currentscan}.T1Maps = imwarp(app.scan_data{app.currentscan}.T1Maps,tform_rotation);
            app.scan_data{app.currentscan}.R1Maps = imwarp(app.scan_data{app.currentscan}.R1Maps,tform_rotation);
            drawimages(app)
        end

        % Button pushed function: ExportallimagesButton
        function ExportallimagesButtonPushed(app, event)
            absolute_scale = app.IndividualNormalisationButton.Value;
            ImageType = app.ImageTypeDropDown.Value;
            fieldlist = app.scan_data{app.currentscan}.fieldpoints;
            timepoints =app.scan_data{app.currentscan}.timepoints;
            n_timepoints = app.scan_data{app.currentscan}.n_timepoints;
            n_fieldpoints = app.scan_data{app.currentscan}.n_fieldpoints;
            
            switch ImageType
                case "Relaxometry Map"
                    if app.DisplayingasT1Button.Value
                        imagesforexport = squeeze(app.scan_data{app.currentscan}.R1Maps);
                        tag = 'R1';
                    else
                        imagesforexport = squeeze(app.scan_data{app.currentscan}.T1Maps);
                        tag = 'T1';
                    end
                    
                    for field = 1:n_fieldpoints
                        imagename = char(([num2str(round(fieldlist(field))) ' mT_' tag 'map.tif']));
                        minD = app.ColourAxisLowerLimitEditField.Value;
                        maxD = app.ColourAxisUpperLimitEditField.Value*1;
                        mapped_image = ind2rgb(double(round((1+imagesforexport(:,:,1,field) - minD) * 255 ./ (maxD - minD))), jet(256)); %this creates a tif RGB image with the same colour scaling as in the GUI
                        imwrite(mapped_image, imagename);
                    end
                    
                case "Phase"
                    imagesforexport = squeeze(app.scan_data{app.currentscan}.phaseimage);
                    tag = 'phasemap';
                    for field = 1:n_fieldpoints
                        for times = 1:n_timepoints
                            imagename = char(([num2str(round(fieldlist(field))) ' mT_' num2str(round(timepoints(field,times))),' ms ' tag '.tif']));
                            imwrite(mat2gray(imagesforexport(:,:,times,field)),imagename);
                        end
                    end
                    
                case "Magnitude"
                    imagesforexport = (app.scan_data{app.currentscan}.magimage);
                   for slice = 1:app.scan_data{app.currentscan}.slices
                    for field = 1:n_fieldpoints
                        for times = 1:n_timepoints
                            imagename = char(([num2str(round(fieldlist(field))) ' mT_' num2str(round(timepoints(field,times).*100)),' ms_slice_' num2str(slice) '.tif']));
                            if absolute_scale
                                imwrite(mat2gray(imagesforexport(:,:,slice,times,field),[0 max(app.scan_data{app.currentscan}.magimage(:)*0.2)]),imagename);
                            else
                                imwrite(mat2gray(imagesforexport(:,:,slice,times,field)),imagename);
                            end
                        end
                    end
                   end
            end
            
        end

        % Button pushed function: ExportstackedimagesButton
        function ExportstackedimagesButtonPushed(app, event)
            absolute_scale = app.IndividualNormalisationButton.Value;
            ImageType = app.ImageTypeDropDown.Value;
            fieldlist = app.scan_data{app.currentscan}.fieldpoints;
            n_timepoints = app.scan_data{app.currentscan}.n_timepoints;
            n_fieldpoints = app.scan_data{app.currentscan}.n_fieldpoints;
            
            switch ImageType
                case "Relaxometry Map"
                    if app.DisplayingasT1Button.Value
                        imagesforexport = squeeze(app.scan_data{app.currentscan}.R1Maps);
                        tag = 'R1';
                    else
                        imagesforexport = squeeze(app.scan_data{app.currentscan}.T1Maps);
                        tag = 'T1';
                    end
                    
                    for field = 1:n_fieldpoints
                        imagename = char((['Stacked ' tag 'map.tif']));
                        minD = app.ColourAxisLowerLimitEditField.Value;
                        maxD = app.ColourAxisUpperLimitEditField.Value;
                        mapped_image = ind2rgb(double(round((1+imagesforexport(:,:,field) - minD) * 255 ./ (maxD - minD))), jet(256)); %this creates a tif RGB image with the same colour scaling as in the GUI
                        imwrite(mapped_image, imagename,'WriteMode','append');
                    end
                    
                case "Phase"
                    imagesforexport = squeeze(app.scan_data{app.currentscan}.phaseimage);
                    for field = 1:n_fieldpoints
                        imagename = char(([num2str(round(fieldlist(field))) ' mT phase stack.tif']));
                        if exist(imagename, 'file')==2
                            delete(imagename);
                        end
                        for times = 1:n_timepoints
                            
                            imwrite(mat2gray(imagesforexport(:,:,times,field)),imagename,'WriteMode','append'); %writemode append adds new images to an existing one, creating a stacked image
                        end
                    end
                    
                case "Magnitude"
                    imagesforexport = (app.scan_data{app.currentscan}.magimage);
                 %%   for slice = 1:app.scan_data{app.currentscan}.slices
                    for slice = 1:1
                    for field = 1:n_fieldpoints
                        imagename = char(([num2str(round(fieldlist(field))) ' mT stack.tif']));
                        if exist(imagename, 'file')==2
                            delete(imagename);
                        end
                        for times = 1:n_timepoints
                            if absolute_scale
                                imwrite(mat2gray(imagesforexport(:,:,slice,times,field),[0 max(app.scan_data{app.currentscan}.magimage(:)*0.2)]),imagename,'WriteMode','append');
                            else
                                imwrite(mat2gray(imagesforexport(:,:,slice,times,field)),imagename,'WriteMode','append');
                            end
                        end
                    end
                    end
            end
        end

        % Callback function
        function ClusterAnalysisButtonPushed(app, event)
            try FFCAnalysis_V3(app.scan_data{app.currentscan})
            catch ME %#ok<NASGU>
                errordlg('Error launching FFC Analysis. Check that FFCAnalysis is included in the MATLAB path')
            end
        end

        % Button pushed function: RegisterImagesButton
        function RegisterImagesButtonPushed(app, event)
         
imtemp = app.scan_data{app.currentscan}.magimage;
imreg = abs(single(register_images(imtemp)));
app.scan_data{app.currentscan}.magimage = imreg;

% imreg = register_images_rotation(imreg);

%app.scan_data{app.currentscan}.originalcomplexkspace =   fft2c(imreg);


drawimages(app)
        end

        % Button pushed function: SNRButton
        function SNRButtonPushed(app, event)
            disp_slice = app.SliceDropDown.Value;
            disp_field = app.EvolutionFieldDropDown.Value;
            disp_time = app.EvolutionTimeDropDown.Value;

            %Slice 41 contains no features
            targetSlice = abs(app.scan_data{app.currentscan}.magimage(:,:,41,disp_time,disp_field));

            %Dimensions
            rows = app.scan_data{app.currentscan}.views;
            cols = app.scan_data{app.currentscan}.samples;

            %Normalising
            targetSliceNorm = mat2gray(targetSlice);
            maskInitial = imbinarize(targetSliceNorm);
            se = strel('disk', 4);
            maskFilled = imclose(maskInitial,se);
            
            %Identify and count pixels in connected groups of pixels in the binary mask
            CC = bwconncomp(maskFilled);
            %Applying the numel function to every cell (group of connected pixels) in
            %the CC array
            numPixels = cellfun(@numel, CC.PixelIdxList);
            %Finding the largest region of pixels; the phantom
            [~, idx] = max(numPixels);

            %Create a binary mask with the largest region of pixels
            maskMROI = zeros(rows, cols, 'logical');
            maskMROI(CC.PixelIdxList{idx}) = true;
    
            %Using imerode to shrink the mask to ensure none of the edge pixels are included in the signal average
            seErode = strel('disk', 6);
            maskMROIRefined = imerode(maskMROI, seErode);
            
            %Mean signal intensity
            S = mean(targetSlice(maskMROIRefined));
    
            %Number and size of noise ROI's
            noiseRegions = 4;
            noiseStd = zeros(noiseRegions, 1);
            noiseBoxSize = round(rows * 0.05);
            buffer = 8;
    
            %Creating a table of starting coordinates to place the noise regions, with an 8 pixel buffer from the edges
            cornerCoords = [
                buffer, buffer;
                cols - noiseBoxSize - buffer, buffer;
                cols - noiseBoxSize - buffer, rows - noiseBoxSize - buffer;
                buffer, rows - noiseBoxSize - buffer
            ];

            figure('Name', 'SNR Analysis', 'NumberTitle', 'off');
            overlayImg = labeloverlay(targetSliceNorm, maskMROIRefined, 'Transparency', 0.7, 'Colormap', 'hot');
            imshow(overlayImg);
            title(['SNR Analysis - Slice ', num2str(disp_slice)], 'FontSize', 14);
            axis square
            hold on;
    
            for i = 1:noiseRegions
                xStart = cornerCoords(i, 1);
                yStart = cornerCoords(i, 2);
        
                maskNoise = false(rows, cols);
                maskNoise(yStart : yStart + noiseBoxSize, xStart : xStart + noiseBoxSize) = true;
                %corrected for Rayleigh distribution of magnitude image before averaging
                noiseStd(i) = std(targetSlice(maskNoise))/0.66;
        
                rectangle('Position', [xStart, yStart, noiseBoxSize, noiseBoxSize], 'EdgeColor', 'g', 'LineWidth', 2);
            end
            hold off;

            noiseAvg = mean(noiseStd);
            %SNR calculation
            snr = S / noiseAvg;
            runningMeanSNR = 16.5;
            SNRStd = 2.7;

            fprintf('--- Signal to Noise Ratio ---\n');
            fprintf('Slice %d SNR: %.1f | Mean Signal: %.1f\n | Mean Noise: %.2f\n', disp_slice, snr, S, noiseAvg);
            if abs(snr - runningMeanSNR) <= 2*SNRStd
                fprintf('Result: PASS\n');
            else
                fprintf('Result: FAIL\n');
            end
        end

        % Value changed function: MultichannelDropDown
        function MultichannelDropDownValueChanged2(app, event)
          %updateimages(app)
        end

        % Button pushed function: RebuildImagesButton
        function RebuildImagesButtonPushed(app, event)
           updateimages(app) 
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)

    % Ignore keypresses when user is typing into controls
    obj = app.UIFigure.CurrentObject;
    if ~isempty(obj) && isprop(obj,'Type')
        if any(strcmp(obj.Type, {'uieditfield','uitextarea','uidropdown','uispinner'}))
            return;
        end
    end

    switch event.Key

        % -------- Slice navigation --------
        case 'uparrow'
            v = app.SliceDropDown.Value;
            if v > min(app.SliceDropDown.ItemsData)
                app.SliceDropDown.Value = v - 1;
                drawimages(app);
            end

        case 'downarrow'
            v = app.SliceDropDown.Value;
            if v < max(app.SliceDropDown.ItemsData)
                app.SliceDropDown.Value = v + 1;
                drawimages(app);
            end

        % -------- Evolution FIELD navigation --------
        case 'leftarrow'
            v = app.EvolutionTimeDropDown.Value;
            if v > min(app.EvolutionTimeDropDown.ItemsData)
                app.EvolutionTimeDropDown.Value = v - 1;
               drawimages(app);
            end

        case 'rightarrow'
            v = app.EvolutionTimeDropDown.Value;
            if v < max(app.EvolutionTimeDropDown.ItemsData)
                app.EvolutionTimeDropDown.Value = v + 1;
                drawimages(app);
            end
    end


        end

        % Button down function: axis_image
        function axis_imageButtonDown(app, event)
            figure(app.UIFigure);   % give UIFigure focus so arrows work
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            function UIFigureWindowKeyPress(app, event)

    switch event.Key
        case 'uparrow'
            v = app.SliceDropDown.Value;
            if v > 1
                app.SliceDropDown.Value = v - 1;
                drawimages(app);
            end

        case 'downarrow'
            v = app.SliceDropDown.Value;
            vmax = max(app.SliceDropDown.ItemsData);
            if v < vmax
                app.SliceDropDown.Value = v + 1;
                drawimages(app);
            end

        case 'leftarrow'
            v = app.EvolutionFieldDropDown.Value;
            if v > 1
                app.EvolutionFieldDropDown.Value = v - 1;
                EvolutionFieldDropDownValueChanged(app, []);
            end

        case 'rightarrow'
            v = app.EvolutionFieldDropDown.Value;
            vmax = max(app.EvolutionFieldDropDown.ItemsData);
            if v < vmax
                app.EvolutionFieldDropDown.Value = v + 1;
                EvolutionFieldDropDownValueChanged(app, []);
            end
    end
end

            
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
        
    % Only respond if click is on the image axes (or an object inside it)
    obj = app.UIFigure.CurrentObject;

    onImageAxes = isequal(obj, app.axis_image);
    if ~onImageAxes
        try
            onImageAxes = isprop(obj,'Parent') && isequal(obj.Parent, app.axis_image);
        catch
        end
    end
    if ~onImageAxes
        return;
    end

    % ---- DOUBLE CLICK = AUTO WINDOW ----
    if strcmp(app.UIFigure.SelectionType, 'open')

        disp_slice = app.SliceDropDown.Value;
        disp_time  = app.EvolutionTimeDropDown.Value;
        disp_field = app.EvolutionFieldDropDown.Value;

        img = app.scan_data{app.currentscan}.magimage(:,:,disp_slice,disp_time,disp_field);

        autoWindowFromImage(app, img);   % your helper
        drawimages(app);
        return;
    end

    % ---- SINGLE CLICK = START DRAG WINDOWING ----
    % Left button only if available
    if isprop(event,'Button') && event.Button ~= 1
        return;
    end

    s.isDragging = true;
    s.startPoint = app.UIFigure.CurrentPoint;
    s.startWW = app.WindowWidthSlider.Value;     % Window Width
    s.startWL = app.WindowLevelSlider.Value;   % Window Level
    app.UIFigure.UserData = s;

    figure(app.UIFigure);
        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
           s = app.UIFigure.UserData;
    if isstruct(s)
        s.isDragging = false;
        app.UIFigure.UserData = s;
    end
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
         s = app.UIFigure.UserData;
    if ~isstruct(s) || ~isfield(s,'isDragging') || ~s.isDragging
        return;
    end

    cp = app.UIFigure.CurrentPoint;           % [x y] in figure pixels
    dx = cp(1) - s.startPoint(1);             % horizontal drag
    dy = cp(2) - s.startPoint(2);             % vertical drag

    % ---- PACS-like sensitivity ----
    k = 1/300;   % increase (e.g. 1/200) if you want faster response

    % Window Width: multiplicative (exponential) so it never goes <= 0
    newWW = s.startWW * exp(dx * k);

    % Window Level: additive, scaled by starting WW (so it feels consistent)
    newWL = s.startWL + dy * (s.startWW * k);

    % ---- Clamp to slider limits ----
    newWW = max(app.WindowWidthSlider.Limits(1),   min(app.WindowWidthSlider.Limits(2),   newWW));
    newWL = max(app.WindowLevelSlider.Limits(1), min(app.WindowLevelSlider.Limits(2), newWL));

    % ---- Update sliders (WW = ContrastSlider, WL = BrightnessSlider) ----
    app.WindowWidthSlider.Value   = newWW;
    app.WindowLevelSlider.Value = newWL;

    drawimages(app);
        end

        % Window scroll wheel function: UIFigure
        function UIFigureWindowScrollWheel(app, event)
             % No data loaded
    if isempty(app.scan_data) || isempty(app.currentscan)
        return;
    end

    % Wheel direction (+ve = scroll down)
    step = event.VerticalScrollCount;

    % Keyboard modifiers
    mods = app.UIFigure.CurrentModifier;
    shiftHeld = any(strcmp(mods, 'shift'));
    ctrlHeld  = any(strcmp(mods, 'control'));

    % Decide what we are scrolling
    if ctrlHeld
        dd = app.EvolutionFieldDropDown;   % Ctrl + wheel → field
    elseif shiftHeld
        dd = app.EvolutionTimeDropDown;    % Shift + wheel → time
    else
        dd = app.SliceDropDown;            % Wheel → slice
    end

    if isempty(dd.ItemsData)
        return;
    end

    % Current value
    v = dd.Value;
    vmin = min(dd.ItemsData);
    vmax = max(dd.ItemsData);

    % Scroll (invert sign here if direction feels wrong)
    vNew = v + step;

    % Clamp
    vNew = max(vmin, min(vmax, vNew));

    if vNew ~= v
        dd.Value = vNew;

        % Field changes must update time list
        if dd == app.EvolutionFieldDropDown
            EvolutionFieldDropDownValueChanged(app, []);
        else
            drawimages(app);
        end
    end
            
        end

        % Value changed function: CombinationDropDown
        function CombinationDropDownValueChanged(app, event)
            value = app.CombinationDropDown.Value;
            
        end

        % Button pushed function: GeometricAccButton
        function GeometricAccButtonPushed(app, event)
            disp_slice = app.SliceDropDown.Value;
            disp_field = app.EvolutionFieldDropDown.Value;
            disp_time = app.EvolutionTimeDropDown.Value;
            
            %Slice 41 contains no features
            targetSlice = abs(app.scan_data{app.currentscan}.magimage(:,:,41,disp_time,disp_field));

            %Dimensions from scan data
            rows = app.scan_data{app.currentscan}.views;
            cols = app.scan_data{app.currentscan}.samples;

            fov = app.scan_data{app.currentscan}.fov;
            pixelSize = fov/rows;

            %Normalising
            targetSliceNorm = mat2gray(targetSlice);
            %Applying a more sensitive threshold
            maskInitial = imbinarize(targetSliceNorm); %Add this line of code if the mask isnt correct , "adaptive", Sensitivity=0.7);
            se = strel('disk', 3);
            maskFilled = imclose(maskInitial, se);

            %Identify and count pixels in connected groups of pixels in the binary mask
            CC = bwconncomp(maskFilled);
            %Applying the numel function to every cell (group of connected pixels) in the CC array
            numPixels = cellfun(@numel, CC.PixelIdxList);
            %Finding the largest region of pixels; the phantom
            [~, idx] = max(numPixels);

            %Create a binary mask with the largest region of pixels
            maskMROI = zeros(rows, cols, 'logical');
            maskMROI(CC.PixelIdxList{idx}) = true;

            stats = regionprops(maskMROI, 'EquivDiameter', 'MajorAxisLength', 'MinorAxisLength', 'Orientation', 'Centroid');
            %The diameter if the region was a perfect circle
            calcDiameter = pixelSize * stats.EquivDiameter;
            %Max and min diameters to help identify any distortion
            maxDiam = stats.MajorAxisLength * pixelSize;
            minDiam = stats.MinorAxisLength * pixelSize;
            
            distortionRatio = stats.MajorAxisLength / stats.MinorAxisLength;

            %Known diameter
            acrDiameter = 190;

            diams = [maxDiam, minDiam];
            diffs = abs((diams) - acrDiameter);

            %The geometric distortion as a percentage
            distortion = 100*(max(diffs)/acrDiameter);

            figure('Name', 'Geometric Accuracy Analysis', 'NumberTitle', 'off');
            imshow(labeloverlay(targetSliceNorm, maskMROI, 'Transparency', 0.8, 'Colormap', 'spring'));
            title(sprintf('Equivalent Diameter: %.2f mm (Major Axis - Minor Axis: %.2f mm)', calcDiameter, maxDiam - minDiam));
            axis square;
            hold on;
            phi = -stats.Orientation;
            x_center = stats.Centroid(1);
            y_center = stats.Centroid(2);
            
            x_line = [x_center - (stats.MajorAxisLength/2) * cosd(phi), x_center + (stats.MajorAxisLength/2) * cosd(phi)];
            y_line = [y_center - (stats.MajorAxisLength/2) * sind(phi), y_center + (stats.MajorAxisLength/2) * sind(phi)];
            
            plot(stats.Centroid(1), stats.Centroid(2), 'r+', 'MarkerSize', 10, 'LineWidth', 2);
            line(x_line, y_line, 'Color', 'cyan', 'LineWidth', 2, 'DisplayName', 'Major Axis');
            hold off;


%ACR guidance states that all measured lengths should be within 2 mm of their true values and will fail if the length differs by more than 3 mm

            if abs(calcDiameter - acrDiameter) <= 2
                resultStr = 'PASS';
            elseif abs(calcDiameter - acrDiameter) <= 3
                resultStr = 'MARGINAL';
            else
                resultStr = 'FAIL';
            end

            fprintf('--- Geometric Accuracy ---\n');
            fprintf('Equivalent Diameter: %.2f mm\nMajor Axis: %.2f mm\nMinor Axis: %.2f mm\nGeometric Distortion: %.3f %%\nResult: %s\n', calcDiameter, maxDiam, minDiam, distortion, resultStr);

%The Redesigned Geometric accuracy procedure

            % disp_slice = app.SliceDropDown.Value;
            % disp_field = app.EvolutionFieldDropDown.Value;
            % disp_time = app.EvolutionTimeDropDown.Value;
            %
            % %Slice 41 contains no features
            % targetSlice = abs(app.scan_data{app.currentscan}.magimage(:,:,41,disp_time,disp_field));
            %
            % %Dimensions from scan data
            % rows = app.scan_data{app.currentscan}.views;
            % cols = app.scan_data{app.currentscan}.samples;
            %
            % fov = app.scan_data{app.currentscan}.fov;
            % pixelSize = fov/rows;
            % acrDiameter = 190;
            %
            % %Normalising
            % targetSliceNorm = mat2gray(targetSlice);
            % %Applying a more sensitive threshold
            % maskInitial = imbinarize(targetSliceNorm); %Add this line of code if the mask isnt correct , "adaptive", Sensitivity=0.7);
            % se = strel('disk', 1);
            % maskFilled = imclose(maskInitial, se);
            %
            % %Identify and count pixels in connected groups of pixels in the binary mask
            % CC = bwconncomp(maskFilled);
            % %Applying the numel function to every cell (group of connected pixels) in the CC array
            % numPixels = cellfun(@numel, CC.PixelIdxList);
            % %Finding the largest region of pixels; the phantom
            % [~, idx] = max(numPixels);
            %
            % %Create a binary mask with the largest region of pixels
            % maskMROI = zeros(rows, cols, 'logical');
            % maskMROI(CC.PixelIdxList{idx}) = true;
            %
            % %New method using Feret diameter/caliper method
            % %Get a central point just for visualisation
            % statsCenter = regionprops(maskMROI, 'Centroid');
            % xc = statsCenter.Centroid(1);
            % yc = statsCenter.Centroid(2);
            %
            % %1 degree steps
            % angles = 0:1:179;
            % %storing the diameter at each angle
            % allDiams = zeros(size(angles));
            %
            % %Storing widths and heights in paired arrays for plotting
            % %the BoundingBox
            % allWidths = zeros(size(angles));
            % allHeights = zeros(size(angles));
            %
            % %Rotate the mask anticlockwise and create a box around it at every
            % %orientation
            % for i = 1:length(angles)
            %     maskRot = imrotate(maskMROI, angles(i), 'nearest', 'crop');
            %     statsRot = regionprops(maskRot, 'BoundingBox');
            %     %The 4th element is the height - ie the diameter
            %     allDiams(i) = statsRot.BoundingBox(4);
            %     allWidths(i)  = statsRot.BoundingBox(3);
            %     allHeights(i) = statsRot.BoundingBox(4);
            % end
            %
            % %Major axis
            % [maxDiamPix, maxIdx] = max(allDiams);
            % maxDiam = maxDiamPix * pixelSize;
            %
            % %Minor axis
            % [minDiamPix, minIdx] = min(allDiams);
            % minDiam = minDiamPix * pixelSize;
            % diffs = abs((allDiams * pixelSize) - acrDiameter);
            %
            % distortionRatio = maxDiam / minDiam;
            %
            % %The geometric distortion as a percentage
            % distortion = 100*(max(diffs)/acrDiameter);
            %
            % calcDiameter = mean(allDiams) * pixelSize;
            %
            % thetaMax = angles(maxIdx);
            % thetaMin = angles(minIdx);
            %
            % figure('Name', 'Geometric Accuracy Analysis', 'NumberTitle', 'off');
            % imshow(labeloverlay(targetSliceNorm, maskMROI, 'Transparency', 0.8, 'Colormap', 'spring'));
            % hold on;
            % axis square;
            % title(sprintf('Equivalent Diameter: %.2f mm', calcDiameter));
            % colormap gray
            %
            % %Lengths of maximum and minimum radii
            % lMax = maxDiamPix / 2;
            % lMin = minDiamPix / 2;
            %
            % %Major axis
            % xMax = [xc - lMax*cosd(90 - thetaMax), xc + lMax*cosd(90 - thetaMax)];
            % yMax = [yc - lMax*sind(90 - thetaMax), yc + lMax*sind(90 - thetaMax)];
            % plot(xMax, yMax, 'y', 'LineWidth', 2);
            %
            % %Minor axis
            % xMin = [xc - lMin*cosd(90 - thetaMin), xc + lMin*cosd(90 - thetaMin)];
            % yMin = [yc - lMin*sind(90 - thetaMin), yc + lMin*sind(90 - thetaMin)];
            % plot(xMin, yMin, 'cyan', 'LineWidth', 2);
            %
            % %BoundingBoxes
            % wMax = allWidths(maxIdx);
            % hMax = allHeights(maxIdx);
            % %corners at (0,0)
            % corners = [-wMax/2, -hMax/2; wMax/2, -hMax/2; wMax/2, hMax/2; -wMax/2, hMax/2; -wMax/2, -hMax/2];
            % %clockwise rotation matrix
            % rMax = [cosd(-thetaMax) -sind(-thetaMax); sind(-thetaMax) cosd(-thetaMax)];
            % %applying rotation to transpose
            % rotMax = (rMax * corners')';
            % %plot relative to centroid
            % plot(rotMax(:,1) + xc, rotMax(:,2) + yc, 'y--', 'LineWidth', 1);
            %
            % wMin = allWidths(minIdx);
            % hMin = allHeights(minIdx);
            % corners_n = [-wMin/2, -hMin/2; wMin/2, -hMin/2; wMin/2, hMin/2; -wMin/2, hMin/2; -wMin/2, -hMin/2];
            % rMin = [cosd(-thetaMin) -sind(-thetaMin); sind(-thetaMin) cosd(-thetaMin)];
            % rotMin = (rMin * corners_n')';
            % plot(rotMin(:,1) + xc, rotMin(:,2) + yc, 'cyan--', 'LineWidth', 1);
            %
            % %Approximate centre
            % plot(xc, yc, 'r+', 'MarkerSize', 10, 'LineWidth', 2);
            % hold off;
            %
            % if abs(calcDiameter - acrDiameter) <= 2
            %     resultStr = 'PASS';
            % elseif abs(calcDiameter - acrDiameter) <= 3
            %     resultStr = 'MARGINAL';
            % else
            %     resultStr = 'FAIL';
            % end
            %
            % fprintf('--- Geometric Accuracy ---\n');
            % fprintf('Equivalent Diameter: %.2f mm\nMajor Axis: %.2f mm\nMinor Axis: %.2f mm\n Geometric Distortion: %.3f %%\nResult: %s\n', calcDiameter, maxDiam, minDiam, distortion, resultStr);
        end

        % Button pushed function: UniformityButton
        function UniformityButtonPushed(app, event)
            disp_slice = app.SliceDropDown.Value;
            disp_field = app.EvolutionFieldDropDown.Value;
            disp_time = app.EvolutionTimeDropDown.Value;

            %Slice 41 contains no features
            targetSlice = abs(app.scan_data{app.currentscan}.magimage(:,:,41,disp_time,disp_field));

            %Extracts the dimesnions fro  the scan data
            rows = app.scan_data{app.currentscan}.views;
            cols = app.scan_data{app.currentscan}.samples;

            fov = app.scan_data{app.currentscan}.fov;
            %Calculating the size of one pixel in mm
            pixelSize = fov/rows;

            %Normalising the pixel values within the slice
            targetSliceNorm = mat2gray(targetSlice);
            %Thresholding using an adaptive sensitivity
            maskInitial = imbinarize(targetSliceNorm); %Add this line of code if the mask isnt correct , "adaptive", Sensitivity=0.7);
            se = strel('disk', 3);
            %Making a smoothed mask of the signal region of the phantom
            maskFilled = imclose(maskInitial, se);

            %Identify and count pixels in connected groups of pixels in the binary mask
            CC = bwconncomp(maskFilled);
            %Applying the numel function to every cell (group of connected pixels) in the CC array
            numPixels = cellfun(@numel, CC.PixelIdxList);
            %Finding the largest region of pixels; the phantom
            [~, idx] = max(numPixels);

            %Create a binary mask with the largest region of pixels
            maskMROI = zeros(rows, cols, 'logical');
            maskMROI(CC.PixelIdxList{idx}) = true;

            %Shrink mask by 6 pixels from the phantom edge
            shrinkPixels = 6;
            maskMROIRefined = imerode(maskMROI, strel('disk', shrinkPixels));
           
            %Create a circular averaging filter to simulate a 1cm^2 ROI (ACR guidance)
            %Get the highest region rather than single pixel to avoid noise outliers
            roiSize = round(sqrt(100/pi) / pixelSize);
            %Making a smoothing filter, disk shaped mask with the specified radius
            h = fspecial('disk', roiSize);
            %imfilter moves the mask over every pixel and replaces the value with the average of its neighbours in the given radius
            %'replicate' assumes the edge pixels of the image are the same as the overhang
            smoothedSlice = imfilter(targetSlice, h, 'replicate');

            %Find high and low mean signal values within the smoothed signal region
            phantomValues = smoothedSlice(maskMROIRefined);
            high = max(phantomValues);
            low = min(phantomValues);

            %Locate the centers of these regions for plotting, by finding pixel equal to the high or low that falls in the refined mask region
            [maxRow, maxCol] = find(smoothedSlice == high & maskMROIRefined, 1);
            [minRow, minCol] = find(smoothedSlice == low & maskMROIRefined, 1);

            %Percent Integral Uniformity (ACR guidance)
            piu = 100 * (1 - ((high - low)/(high + low)));
            runningMeanPIU = 74.4;
            PIUStd = 1.4;

            figure('Name', 'Image Uniformity Analysis', 'NumberTitle', 'off');
            %Displaying the original slice with the refined mask overlay
            imshow(labeloverlay(targetSliceNorm, maskMROI, 'Transparency', 0.8, 'Colormap', 'summer'));
            title(sprintf('PIU: %.2f%% (High: %.2f, Low: %.2f)', piu, high, low));
            axis square;
            hold on;
            %creating a circular element which had the radius required, to
            %display onto the figure
            viscircles([maxCol, maxRow], roiSize, 'EdgeColor', 'r', 'LineWidth', 2);
            viscircles([minCol, minRow], roiSize, 'EdgeColor', 'b', 'LineWidth', 2);
            %Non numerical 'placeholder' for the legend, doesn't work with
            %viscircles
            hMax = plot(NaN, NaN, 'or', 'LineWidth', 2);
            hMin = plot(NaN, NaN, 'ob', 'LineWidth', 2); 
            hMask = plot(NaN, NaN, 's', 'MarkerEdgeColor', 'none', 'MarkerFaceColor', [0.8 1 0.8]);
            legend([hMask, hMax, hMin], {'Refined ROI', 'Max Signal ROI', 'Min Signal ROI'}, 'Location', 'southoutside', 'Orientation', 'horizontal');
            hold off;

            fprintf('--- Image Uniformity ---\n');
            fprintf('PIU: %.1f%%\nMax ROI Mean: %.1f\nMin ROI Mean: %.1f\n', piu, high, low);
            
             if abs(piu - runningMeanPIU) <= 2*PIUStd
                fprintf('Result: PASS\n');
             else
                fprintf('Result: FAIL\n');
            end
        end

        % Button pushed function: THKButton
        function THKButtonPushed(app, event)
            disp_slice = app.SliceDropDown.Value;
            disp_field = app.EvolutionFieldDropDown.Value;
            disp_time = app.EvolutionTimeDropDown.Value;

            %Slice 65 for the signal ramps
            targetSlice = abs(app.scan_data{app.currentscan}.magimage(:,:,65,disp_time,disp_field));

            %Extracts the dimesnions fro  the scan data
            rows = app.scan_data{app.currentscan}.views;
            cols = app.scan_data{app.currentscan}.samples;

            fov = app.scan_data{app.currentscan}.fov;
            %Calculating the size of one pixel in mm
            pixelSize = fov/rows;

            %Normalising the pixel values within the slice
            targetSliceNorm = mat2gray(targetSlice);

            figure('Name', 'Slice Thickness - Manual')
            imagesc(targetSliceNorm)
            title('Slice Thickness - Manual',  'FontSize', 20)
            axis square
            axis off
            colormap gray
            annotation('textbox', [0.28, 0.0, 0.1, 0.1], 'String', "Draw 2 lines, measuring the lengths of the signal bars")

            length1 = drawline('Color', 'b');
            length2 = drawline('Color', 'g');

            %The position of the roi line is given by a 2x2 matrix where each row
            %is one of the end points of the line
            pos1 = length1.Position;
            pos2 = length2.Position;

            %Use the geometric distance formula to calculate the length in pixels
            pixelLength1 = sqrt(diff(pos1(:,1))^2 + diff(pos1(:,2))^2);
            pixelLength2 = sqrt(diff(pos2(:,1))^2 + diff(pos2(:,2))^2);

            top = pixelSize*pixelLength1;
            bottom = pixelSize*pixelLength2;

            %Calculate the slice thickness
            sliceThickness = 0.2 * ( (top * bottom) / (top + bottom) );
            trueThickness = app.scan_data{app.currentscan}.thk; %mm

            %Print the result
            fprintf('Prescribed Slice Thickness: %.1f mm\n', trueThickness')
            fprintf('Slice Thickness: %.1f mm\n', sliceThickness);

            if abs(sliceThickness - trueThickness) <= 0.14*trueThickness
                fprintf('Result: PASS\n');
            elseif abs(sliceThickness - trueThickness) >= 0.14*trueThickness && abs(sliceThickness - trueThickness) <= 0.2*trueThickness
                fprintf('Result: Over 14%% difference, but under 20%% failure point\n');
            else
                fprintf('Result: FAIL (Outwith 20%% tolerance)\n');
            end
        end

        % Button pushed function: AllButton
        function AllButtonPushed(app, event)
%DEFINITIONS
            %Current GUI image
            disp_slice = app.SliceDropDown.Value;
            disp_field = app.EvolutionFieldDropDown.Value;
            disp_time = app.EvolutionTimeDropDown.Value;

            %Hardcoded slices
            sliceSNR = 41;
            sliceGEO = 41;
            sliceUNI = 41;
            sliceTHI = 65;

            targetSliceSNR = abs(app.scan_data{app.currentscan}.magimage(:,:,sliceSNR,disp_time,disp_field));
            targetSliceGEO = abs(app.scan_data{app.currentscan}.magimage(:,:,sliceGEO,disp_time,disp_field));
            targetSliceUNI = abs(app.scan_data{app.currentscan}.magimage(:,:,sliceUNI,disp_time,disp_field));
            targetSliceTHI = abs(app.scan_data{app.currentscan}.magimage(:,:,sliceTHI,disp_time,disp_field));

            targetSliceSNRNorm = mat2gray(targetSliceSNR);
            targetSliceGEONorm = mat2gray(targetSliceGEO);
            targetSliceUNINorm = mat2gray(targetSliceUNI);
            targetSliceTHINorm = mat2gray(targetSliceTHI);

            %Dimensions of the scan data
            rows = app.scan_data{app.currentscan}.views;
            cols = app.scan_data{app.currentscan}.samples;

            fov = app.scan_data{app.currentscan}.fov;

            pixelSize = fov/rows;

            acrDiameter = 190;


%PRE FIGURE CALCULATIONS
            maskInitialSNR = imbinarize(targetSliceSNRNorm);
            seSNR = strel('disk', 4);
            maskFilledSNR = imclose(maskInitialSNR,seSNR);
            
            %Identify and count pixels in connected groups of pixels in the binary mask
            CCSNR = bwconncomp(maskFilledSNR);
            %Applying the numel function to every cell (group of connected pixels) in
            %the CC array
            numPixelsSNR = cellfun(@numel, CCSNR.PixelIdxList);
            %Finding the largest region of pixels; the phantom
            [~, idx] = max(numPixelsSNR);

            %Create a binary mask with the largest region of pixels
            maskMROISNR = zeros(rows, cols, 'logical');
            maskMROISNR(CCSNR.PixelIdxList{idx}) = true;
    
            %Using imerode to shrink the mask to ensure none of the edge pixels are included in the signal average
            seErodeSNR = strel('disk', 6);
            maskMROIRefinedSNR = imerode(maskMROISNR, seErodeSNR);
            
            %Mean signal intensity
            S = mean(targetSliceSNR(maskMROIRefinedSNR));
    
            %Number and size of noise ROI's
            noiseRegions = 4;
            noiseStd = zeros(noiseRegions, 1);
            noiseBoxSize = round(rows * 0.05);
            buffer = 8;
    
            %Creating a table of starting coordinates to place the noise regions, with an 8 pixel buffer from the edges
            cornerCoords = [
                buffer, buffer;
                cols - noiseBoxSize - buffer, buffer;
                cols - noiseBoxSize - buffer, rows - noiseBoxSize - buffer;
                buffer, rows - noiseBoxSize - buffer
            ];

            %Applying a more sensitive threshold
            maskInitialGEO = imbinarize(targetSliceGEONorm); %Add this line of code if the mask isnt correct , "adaptive", Sensitivity=0.7);
            seGEO = strel('disk', 3);
            maskFilledGEO = imclose(maskInitialGEO, seGEO);

            %Identify and count pixels in connected groups of pixels in the binary mask
            CCGEO = bwconncomp(maskFilledGEO);
            %Applying the numel function to every cell (group of connected pixels) in the CC array
            numPixelsGEO = cellfun(@numel, CCGEO.PixelIdxList);
            %Finding the largest region of pixels; the phantom
            [~, idx] = max(numPixelsGEO);

            %Create a binary mask with the largest region of pixels
            maskMROIGEO = zeros(rows, cols, 'logical');
            maskMROIGEO(CCGEO.PixelIdxList{idx}) = true;

            stats = regionprops(maskMROIGEO, 'EquivDiameter', 'MajorAxisLength', 'MinorAxisLength', 'Orientation', 'Centroid');
            %The diameter if the region was a perfect circle
            calcDiameterOriginal = pixelSize * stats.EquivDiameter;
            %Max and min diameters to help identify any distortion
            maxDiamOriginal = stats.MajorAxisLength * pixelSize;
            minDiamOriginal = stats.MinorAxisLength * pixelSize;
            distortionRatio = stats.MajorAxisLength / stats.MinorAxisLength;

            xc = stats.Centroid(1);
            yc = stats.Centroid(2);

            %1 degree steps
            angles = 0:1:179;
            %storing the diameter at each angle
            allDiams = zeros(size(angles));
            allWidths = zeros(size(angles));
            allHeights = zeros(size(angles));

            %Rotate the mask anticlockwise and create a box around it at every
            %orientation
            for i = 1:length(angles)
                %anticlockwise rotation
                maskRot = imrotate(maskMROIGEO, angles(i), 'nearest', 'crop');
                statsRot = regionprops(maskRot, 'BoundingBox');
                %The 4th element is the height - ie the diameter
                allDiams(i) = statsRot.BoundingBox(4);

                allWidths(i)  = statsRot.BoundingBox(3);
                allHeights(i) = statsRot.BoundingBox(4);
            end

            %Major axis
            [maxDiamPix, maxIdx] = max(allDiams);
            maxDiam = maxDiamPix * pixelSize;

            %Minor axis
            [minDiamPix, minIdx] = min(allDiams);
            minDiam = minDiamPix * pixelSize;

            thetaMax = angles(maxIdx);
            thetaMin = angles(minIdx);

            %Thresholding using an adaptive sensitivity
            maskInitialUNI = imbinarize(targetSliceUNINorm); %Add this line of code if the mask isnt correct , "adaptive", Sensitivity=0.7);
            seUNI = strel('disk', 3);
            %Making a smoothed mask of the signal region of the phantom
            maskFilledUNI = imclose(maskInitialUNI, seUNI);

            %Identify and count pixels in connected groups of pixels in the binary mask
            CCUNI = bwconncomp(maskFilledUNI);
            %Applying the numel function to every cell (group of connected pixels) in the CC array
            numPixelsUNI = cellfun(@numel, CCUNI.PixelIdxList);
            %Finding the largest region of pixels; the phantom
            [~, idx] = max(numPixelsUNI);

            %Create a binary mask with the largest region of pixels
            maskMROIUNI = zeros(rows, cols, 'logical');
            maskMROIUNI(CCUNI.PixelIdxList{idx}) = true;

            %Shrink mask by 20mm from the phantom edge
            shrinkPixelsUNI = round(20 / pixelSize);
            maskMROIRefinedUNI = imerode(maskMROIUNI, strel('disk', shrinkPixelsUNI));
           
            %Create a circular averaging filter to simulate a 1cm^2 ROI (ACR guidance)
            %Get the highest region rather than single pixel to avoid noise outliers
            roiSize = round(sqrt(100/pi) / pixelSize);
            %Making a smoothing filter, disk shaped mask with the specified radius
            h = fspecial('disk', roiSize);
            %imfilter moves the mask over every pixel and replaces the value with the average of its neighbours in the given radius
            %'replicate' assumes the edge pixels of the image are the same as the overhang
            smoothedSlice = imfilter(targetSliceUNI, h, 'replicate');

            %Find high and low mean signal values within the smoothed signal region
            phantomValues = smoothedSlice(maskMROIRefinedUNI);
            high = max(phantomValues);
            low = min(phantomValues);

            %Locate the centers of these regions for plotting, by finding pixel equal to the high or low that falls in the refined mask region
            [maxRow, maxCol] = find(smoothedSlice == high & maskMROIRefinedUNI, 1);
            [minRow, minCol] = find(smoothedSlice == low & maskMROIRefinedUNI, 1);


%FIGURES
            %SLICE THICKNESS MANUAL
            figure('Name', 'Slice Thickness')
            imagesc(targetSliceTHINorm)
            title('Slice Thickness - Manual',  'FontSize', 20)
            axis square
            axis off
            colormap gray
            annotation('textbox', [0.28, 0.0, 0.1, 0.1], 'String', "Draw 2 lines, measuring the lengths of the signal bars")

            length1 = drawline('Color', 'b');
            length2 = drawline('Color', 'g');

            name = sprintf('QA Test (%s)', datetime("now"));
            figure("Name", name)
            t = tiledlayout(2,2, "TileSpacing", "tight");
            axis square
            axis off
            colormap gray
            title(t,'Daily QA Test')

            %SNR
            nexttile
            imshow(labeloverlay(targetSliceSNRNorm, maskMROIRefinedSNR, 'Transparency', 0.7, 'Colormap', 'hot'));
            for i = 1:noiseRegions
                xStart = cornerCoords(i, 1);
                yStart = cornerCoords(i, 2);

                maskNoise = false(rows, cols);
                maskNoise(yStart : yStart + noiseBoxSize, xStart : xStart + noiseBoxSize) = true;
                noiseStd(i) = std(targetSliceSNR(maskNoise))/0.66;

                rectangle('Position', [xStart, yStart, noiseBoxSize, noiseBoxSize], 'EdgeColor', 'g', 'LineWidth', 2);
            end
            title('SNR')

            %GEOMETRIC ACCURACY ORIGINAL
            nexttile
            imshow(labeloverlay(targetSliceGEONorm, maskMROIGEO, 'Transparency', 0.8, 'Colormap', 'spring'));
            hold on;
            
            % Calculate the diameter line endpoints
            phi = -stats.Orientation; % Orientation is in degrees
            x_center = stats.Centroid(1);
            y_center = stats.Centroid(2);

            % Line for Major Axis
            x_line1 = [x_center - (stats.MajorAxisLength/2) * cosd(phi), x_center + (stats.MajorAxisLength/2) * cosd(phi)];
            y_line1 = [y_center - (stats.MajorAxisLength/2) * sind(phi), y_center + (stats.MajorAxisLength/2) * sind(phi)];

            major = line(x_line1, y_line1, 'Color', 'y', 'LineWidth', 2, 'DisplayName', 'Major Axis');

            % Line for Minor Axis
            x_line2 = [x_center - (stats.MinorAxisLength/2) * cosd(phi+90), x_center + (stats.MinorAxisLength/2) * cosd(phi+90)];
            y_line2 = [y_center - (stats.MinorAxisLength/2) * sind(phi+90), y_center + (stats.MinorAxisLength/2) * sind(phi+90)];

            minor = line(x_line2, y_line2, 'Color', 'cyan', 'LineWidth', 2, 'DisplayName', 'Minor Axis');
            legend([major, minor], {'Major Axis', 'Minor Axis'}, 'Location', 'southoutside', 'Orientation', 'horizontal');
            hold off;
            title('Geometric Accuracy Original')

            %GEOMETRIC ACCURACY REDESIGNED
            % figure('Name', 'Geometric Accuracy - Automatic')
            % imshow(labeloverlay(targetSliceNormGEO, maskMROIGEO, 'Transparency', 0.7, 'Colormap', 'spring'));
            % hold on;
            % axis square;
            % colormap gray
            % 
            % %lengths of max and min radii
            % lMax = maxDiamPix / 2;
            % lMin = minDiamPix / 2;
            % 
            % %Major axis
            % xMax = [xc - lMax*cosd(90 - thetaMax), xc + lMax*cosd(90 - thetaMax)];
            % yMax = [yc - lMax*sind(90 - thetaMax), yc + lMax*sind(90 - thetaMax)];
            % plot(xMax, yMax, 'y', 'LineWidth', 2);
            % 
            % %Minor axis
            % xMin = [xc - lMin*cosd(90 - thetaMin), xc + lMin*cosd(90 - thetaMin)];
            % yMin = [yc - lMin*sind(90 - thetaMin), yc + lMin*sind(90 - thetaMin)];
            % plot(xMin, yMin, 'cyan', 'LineWidth', 2);
            % 
            % %BoundingBoxes
            % wMax = allWidths(maxIdx);
            % hMax = allHeights(maxIdx);
            % %corners at (0,0)
            % corners = [-wMax/2, -hMax/2; wMax/2, -hMax/2; wMax/2, hMax/2; -wMax/2, hMax/2; -wMax/2, -hMax/2];
            % %clockwise rotation matrix
            % rMax = [cosd(-thetaMax) -sind(-thetaMax); sind(-thetaMax) cosd(-thetaMax)];
            % %applying rotation to transpose
            % rotMax = (rMax * corners')';
            % %plot relative to centroid
            % plot(rotMax(:,1) + xc, rotMax(:,2) + yc, 'y--', 'LineWidth', 1);
            % 
            % wMin = allWidths(minIdx);
            % hMin = allHeights(minIdx);
            % corners_n = [-wMin/2, -hMin/2; wMin/2, -hMin/2; wMin/2, hMin/2; -wMin/2, hMin/2; -wMin/2, -hMin/2];
            % rMin = [cosd(-thetaMin) -sind(-thetaMin); sind(-thetaMin) cosd(-thetaMin)];
            % rotMin = (rMin * corners_n')';
            % plot(rotMin(:,1) + xc, rotMin(:,2) + yc, 'cyan--', 'LineWidth', 1);
            % 
            % %Approximate centre
            % plot(xc, yc, 'r+', 'MarkerSize', 10, 'LineWidth', 2);
            % hold off;
            % % title('Geometric Accuracy Redesigned');

            %IMAGE UNIFORMITY
            nexttile
            imshow(labeloverlay(targetSliceUNINorm, maskMROIUNI, 'Transparency', 0.8, 'Colormap', 'summer'));
            hold on;
            %creating a circular element which had the radius required, to display onto the figure
            viscircles([maxCol, maxRow], roiSize, 'EdgeColor', 'r', 'LineWidth', 2);
            viscircles([minCol, minRow], roiSize, 'EdgeColor', 'b', 'LineWidth', 2);
            %Non numerical 'placeholder' for the legend, doesn't work with viscircles
            hMax = plot(NaN, NaN, 'or', 'LineWidth', 2);
            hMin = plot(NaN, NaN, 'ob', 'LineWidth', 2); 
            legend([hMax, hMin], {'Max Signal ROI', 'Min Signal ROI'}, 'Location', 'southoutside', 'Orientation', 'horizontal');
            hold off;
            title('Image Uniformity')

            %SLICE THICKNESS ACCURACY
            nexttile
            imshow(targetSliceTHINorm)
            hold on
            line(length1.Position(:,1), length1.Position(:,2), 'Color', '#FF5C00', 'LineWidth', 2, 'DisplayName', 'Length 1');
            lines = line(length2.Position(:,1), length2.Position(:,2), 'Color', '#FF5C00', 'LineWidth', 2, 'DisplayName', 'Length 2');
            legend(lines, 'Signal Ramps', 'Location', 'southoutside', 'Orientation', 'horizontal');
            hold off
            title('Slice Thickness Accuracy')
            

%POST FIGURE CALCULATIONS
            %Calculation of the mean noise which is corrected by dividing by 0.66 which accounts for the Rayleigh distribution of the noise in the magnitude image.
            noiseAvg = mean(noiseStd); 
            %SNR calculation
            snr = S / noiseAvg;
            runningMeanSNR = 16.5;
            SNRStd = 2.7;

            %Calculating the geometric distortion
            diams = [maxDiamOriginal, minDiamOriginal];
            diffsOriginal = abs((diams) - acrDiameter);

            %The geometric distortion as a percentage
            distortionOriginal = 100*(max(diffsOriginal)/acrDiameter);

            %Calculating the geometric distortion
            diffs = abs((allDiams * pixelSize) - acrDiameter);
            distortion = 100*(max(diffs)/acrDiameter);

            %Calculating the equivalent diameter
            calcDiameter = mean(allDiams) * pixelSize;

            %Calculate Percent Integral Uniformity
            piu = 100 * (1 - ((high - low)/(high + low)));
            runningMeanPIU = 74.4;
            PIUStd = 1.4;

            %The position of the roi line is given by a 2x2 matrix where each row
            %is one of the end points of the line
            pos1 = length1.Position;
            pos2 = length2.Position;

            %Use the geometric distance formula to calculate the length in pixels
            pixelLength1 = sqrt(diff(pos1(:,1))^2 + diff(pos1(:,2))^2);
            pixelLength2 = sqrt(diff(pos2(:,1))^2 + diff(pos2(:,2))^2);

            top = pixelSize*pixelLength1;
            bottom = pixelSize*pixelLength2;

            %Calculate the Slice Thickness
            sliceThickness = 0.2 * ( (top * bottom) / (top + bottom) );
            trueThickness = app.scan_data{app.currentscan}.thk; %mm


%OUTPUTS
            fprintf('--- Signal to Noise Ratio ---\n');
            fprintf('SNR: %.1f | Mean Signal: %.1f\n | Mean Noise: %.2f\n', snr, S, noiseAvg);
            %resSNR = snr;
            if abs(snr - runningMeanSNR) <= 2*SNRStd
                resSNR = "pass";
                fprintf('Result: PASS\n');
            else
                resSNR = "fail";
                fprintf('Result: FAIL\n');
            end

            %ACR guidance states that all measured lengths should be within 2 mm of their true values and will fail if the length differs by more than 3 mm
            fprintf('--- Geometric Accuracy Original ---\n');
            fprintf('Equivalent Diameter: %.1f mm\nMajor Axis: %.1f mm\nMinor Axis: %.1f mm\nGeometric Distortion: %.3f %%\n', calcDiameterOriginal, maxDiamOriginal, minDiamOriginal, distortionOriginal);
            if abs(calcDiameterOriginal - acrDiameter) <= 2
                resGEO = "pass";
                fprintf('Result: PASS\n');
            elseif abs(calcDiameterOriginal - acrDiameter) <= 3
                resGEO = "marginal";
                fprintf('Result: MARGINAL (Over 2 mm difference, but below 3 mm failure threshold)\n');
            else
                resGEO = "fail";
                fprintf('Result: FAIL (over 3 mm)\n');
            end

            % fprintf('--- Geometric Accuracy Redesigned ---\n');
            % fprintf('Equivalent Diameter: %.1f mm\nMajor Axis: %.1f mm\nMinor Axis: %.1f mm\nGeometric Distortion: %.3f %%\n', calcDiameter, maxDiam, minDiam, distortion);
            % if abs(calcDiameter - acrDiameter) <= 2
            %     resGEO = "pass";
            %     fprintf('Result: PASS\n');
            % elseif abs(calcDiameterOriginal - acrDiameter) <= 3
            %     resGEO = "marginal";
            %     fprintf('Result: MARGINAL (Over 2 mm difference, but below 3 mm failure threshold)\n');
            % else
            %     resGEO = "fail";
            %     fprintf('Result: FAIL (over 3 mm)\n');
            % end

            % %For images acquired using the large phantom on MRI systems with field strengths less than 3 Tesla, PIU should be greater than or equal to 87.5% and will fail if less than 85%.
            % fprintf('--- Image Uniformity ---\n');
            % fprintf('PIU: %.2f%%\nMax ROI Mean: %.2f\nMin ROI Mean: %.2f\n', piu, high, low);
            % 
            % if piu >= 87.5
            %     resUNI = "pass";
            %     fprintf('Result: PASS\n');
            % elseif piu >= 85
            %     resUNI = "marginal";
            %     fprintf('Result: MARGINAL (Pass >= 85%%, but below 87.5%%)\n');
            % else
            %     resUNI = "fail";
            %     fprintf('Result: FAIL (below 85%%)\n');
            % end
            fprintf('--- Image Uniformity ---\n');
            fprintf('PIU: %.1f%%\nMax ROI Mean: %.1f\nMin ROI Mean: %.1f\n', piu, high, low);
            
             if abs(piu - runningMeanPIU) <= 2*PIUStd
                resUNI = "pass";
                fprintf('Result: PASS\n');
            else
                resUNI = "fail";
                fprintf('Result: FAIL\n');
            end

            %ACR guidance states that all measured thicknesses should be 5.0 mm +/- 0.7 mm.
            %It will fail if it is +/- 1.0 mm however I think that our
            %prescribed thickness is ~ 2 mm (1.9889 mm)
            fprintf('--- Slice Thickness Accuracy ---\n');
            fprintf('Prescribed Slice Thickness: %.1f mm\n', trueThickness')
            fprintf('Slice Thickness: %.1f mm\n', sliceThickness);

            if abs(sliceThickness - trueThickness) <= 0.14*trueThickness
                 resTHI = "pass";
                fprintf('Result: PASS\n');
            elseif abs(sliceThickness - trueThickness) >= 0.14*trueThickness && abs(sliceThickness - trueThickness) <= 0.2*trueThickness
                resTHI = "marginal";
                fprintf('Result: MARGINAL (Over 14%% difference, but under 20%% failure point)\n');
            else
                resTHI = "fail";
                fprintf('Result: FAIL (Outwith 20%% tolerance)\n');
            end

            results = [resSNR resGEO resUNI resTHI];
          %  writematrix(results,"C:\Users\power\MRI Project\results.xlsx", 'WriteMode', 'append');
        end

        % Button pushed function: T2Button
        function T2ButtonPushed(app, event)
            %Get the current slice, field and timepoint from the UI
            disp_slice = app.SliceDropDown.Value;
            disp_field = app.EvolutionFieldDropDown.Value;
            disp_time = app.EvolutionTimeDropDown.Value;

            %Initialising
            %List of echo times
            times = app.scan_data{app.currentscan}.timepoints;
            %Number of echos
            echos = length(times);
            %Number of slices
            slices = size(app.scan_data{app.currentscan}.magimage,3);
            %Matrix dimensions of the image
            rows = app.scan_data{app.currentscan}.views;
            cols = app.scan_data{app.currentscan}.samples;

            %Empty arrays for signal values
            sliceT2s = zeros(slices, 1);
            signalsAll = zeros(echos,1);

            %Image Processing
            %Magnitude image of the empty slice (6)
            targetSliceT2 = abs(app.scan_data{app.currentscan}.magimage(:,:,6,disp_time,disp_field));
            %Normalising
            targetSliceT2Norm = mat2gray(targetSliceT2);
            %Making a mask - using SNR and Geometric Accuracy method
            maskInitialT2 = imbinarize(mat2gray(targetSliceT2Norm));
            seT2 = strel('disk', 3);
            maskFilledT2 = imclose(maskInitialT2, seT2);

            %Identify and count pixels in connected groups of pixels in the binary mask
            CCT2 = bwconncomp(maskFilledT2);
            %Applying the numel function to every cell (group of connected pixels) in the CC array
            numPixelsT2 = cellfun(@numel, CCT2.PixelIdxList);
            %Finding the largest region of pixels; the phantom
            [~, idx] = max(numPixelsT2);

            %Create a binary mask with the largest region of pixels
            maskMROIT2 = zeros(rows, cols, 'logical');
            maskMROIT2(CCT2.PixelIdxList{idx}) = true;

            seErode = strel('disk', 1);
            maskMROIT2Refined = imerode(maskMROIT2, seErode);
            
            %Mask visual check
            figure()
            imshow(labeloverlay(targetSliceT2Norm, maskMROIT2Refined, 'Transparency', 0.8, 'Colormap', 'spring'));

            %Loop through all slices to get the mean signal in the mask pixels at
            %every TE
            % figure('Name', 'T2 Curves');
            % hold on;

            for s = 1:slices
                signals = zeros(echos,1);

                for n = 1:echos
                    currentEchoSlice = abs(app.scan_data{app.currentscan}.magimage(:,:,s, n, disp_field));
                    signals(n) = mean(currentEchoSlice(maskMROIT2Refined));
                    signalsAll(n) = signalsAll(n) + signals(n);
                end

                %Curve fitting for each slice
                sliceRes = T2Curve(times, signals);
                R2 = sliceRes.R2;
                sliceT2s(s) = 1/R2;

                % plot(sliceRes, times, signals) 
            end

            % title('T2 Curves');
            % xlabel('TE (s)');
            % ylabel('Signal');
            % hold off;

            %Average total signal per slice at every TE
            signalsAvg = signalsAll / slices;
            %Curve fitting the average signal
            allRes = T2Curve(times, signalsAvg);
            R2 = allRes.R2;
            T2All = 1/R2;

            %Plots
            figure()
            plot(allRes, times, signalsAvg);
            grid on
            title('Average Signal T2 Fit');
            xlabel('TE (s)');
            ylabel('Average Signal');

            figure('Name', 'T2 Variation Over Each Slice');
            plot(1:slices, sliceT2s, 'b', LineWidth = 4);
            grid on;
            xlabel('Slice Number');
            ylabel('T2 Value (s)');
            title(['T2 Variation by Slice (Average T2: ', num2str(T2All, '%.2f'), ' s)']);

            %Curve fitting function
            function [fitresult, gof] = T2Curve(times, signals)
                %CREATEFIT(TIMES,SIGNALS)
                %  Create a fit.
                %
                %  Data for 'T2' fit:
                %      X Input: times
                %      Y Output: signals
                %  Output:
                %      fitresult : a fit object representing the fit.
                %      gof : structure with goodness-of fit info.
                %
                %  See also FIT, CFIT, SFIT.

                %  Auto-generated by MATLAB on 24-Feb-2026 13:36:28


                %% Fit: 'T2'.
                [xData, yData] = prepareCurveData( times, signals);

                % Set up fittype and options.
                ft = fittype( 'S0*exp(-R2*x)+c', 'independent', 'x', 'dependent', 'y' );
                opts = fitoptions( 'Method', 'NonlinearLeastSquares' );
                opts.Display = 'Off';
                opts.Lower = [0 0 -Inf];
                opts.StartPoint = [80 6 2.5];

                % Fit model to data.
                [fitresult, gof] = fit( xData, yData, ft, opts );

                % Plot fit with data.
                %figure( 'Name', 'T2' );
                %h = plot( fitresult, xData, yData );
                %legend( h, 'signals vs. times', 'T2', 'Location', 'NorthEast', 'Interpreter', 'none' );
                % Label axes
                %xlabel( 'times', 'Interpreter', 'none' );
                %ylabel( 'signals', 'Interpreter', 'none' );
                %grid on
            end
        end

        % Callback function: not associated with a component
        function GeometricAccButton_2Pushed(app, event)
            disp_slice = app.SliceDropDown.Value;
            disp_field = app.EvolutionFieldDropDown.Value;
            disp_time = app.EvolutionTimeDropDown.Value;

            %Slice 41 contains no features
            targetSlice = abs(app.scan_data{app.currentscan}.magimage(:,:,41,disp_time,disp_field));

            %Dimensions from scan data
            rows = app.scan_data{app.currentscan}.views;
            cols = app.scan_data{app.currentscan}.samples;

            fov = app.scan_data{app.currentscan}.fov;
            pixelSize = fov/rows;
            acrDiameter = 190;

            %Normalising
            targetSliceNorm = mat2gray(targetSlice);
            %Applying a more sensitive threshold
            maskInitial = imbinarize(targetSliceNorm); %Add this line of code if the mask isnt correct , "adaptive", Sensitivity=0.7);
            se = strel('disk', 1);
            maskFilled = imclose(maskInitial, se);

            %Identify and count pixels in connected groups of pixels in the binary mask
            CC = bwconncomp(maskFilled);
            %Applying the numel function to every cell (group of connected pixels) in the CC array
            numPixels = cellfun(@numel, CC.PixelIdxList);
            %Finding the largest region of pixels; the phantom
            [~, idx] = max(numPixels);

            %Create a binary mask with the largest region of pixels
            maskMROI = zeros(rows, cols, 'logical');
            maskMROI(CC.PixelIdxList{idx}) = true;

            %New method using Feret diameter/caliper method
            %Get a central point just for visualisation
            statsCenter = regionprops(maskMROI, 'Centroid');
            xc = statsCenter.Centroid(1);
            yc = statsCenter.Centroid(2);

            %1 degree steps
            angles = 0:1:179;
            %storing the diameter at each angle
            allDiams = zeros(size(angles));

            %Storing widths and heights in paired arrays for plotting
            %the BoundingBox
            allWidths = zeros(size(angles));
            allHeights = zeros(size(angles));

            %Rotate the mask anticlockwise and create a box around it at every
            %orientation
            for i = 1:length(angles)
                maskRot = imrotate(maskMROI, angles(i), 'nearest', 'crop');
                statsRot = regionprops(maskRot, 'BoundingBox');
                %The 4th element is the height - ie the diameter
                allDiams(i) = statsRot.BoundingBox(4);
                allWidths(i)  = statsRot.BoundingBox(3);
                allHeights(i) = statsRot.BoundingBox(4);
            end

            %Major axis
            [maxDiamPix, maxIdx] = max(allDiams);
            maxDiam = maxDiamPix * pixelSize;

            %Minor axis
            [minDiamPix, minIdx] = min(allDiams);
            minDiam = minDiamPix * pixelSize;
            diffs = abs((allDiams * pixelSize) - acrDiameter);

            distortionRatio = maxDiam / minDiam;

            %The geometric distortion as a percentage
            distortion = 100*(max(diffs)/acrDiameter);

            calcDiameter = mean(allDiams) * pixelSize;

            thetaMax = angles(maxIdx);
            thetaMin = angles(minIdx);

            figure('Name', 'Geometric Accuracy Analysis', 'NumberTitle', 'off');
            imshow(labeloverlay(targetSliceNorm, maskMROI, 'Transparency', 0.8, 'Colormap', 'spring'));
            hold on;
            axis square;
            title(sprintf('Equivalent Diameter: %.2f mm', calcDiameter));
            colormap gray

            %Lengths of maximum and minimum radii
            lMax = maxDiamPix / 2;
            lMin = minDiamPix / 2;

            %Major axis
            xMax = [xc - lMax*cosd(90 - thetaMax), xc + lMax*cosd(90 - thetaMax)];
            yMax = [yc - lMax*sind(90 - thetaMax), yc + lMax*sind(90 - thetaMax)];
            plot(xMax, yMax, 'y', 'LineWidth', 2);

            %Minor axis
            xMin = [xc - lMin*cosd(90 - thetaMin), xc + lMin*cosd(90 - thetaMin)];
            yMin = [yc - lMin*sind(90 - thetaMin), yc + lMin*sind(90 - thetaMin)];
            plot(xMin, yMin, 'cyan', 'LineWidth', 2);

            %BoundingBoxes
            wMax = allWidths(maxIdx);
            hMax = allHeights(maxIdx);
            %corners at (0,0)
            corners = [-wMax/2, -hMax/2; wMax/2, -hMax/2; wMax/2, hMax/2; -wMax/2, hMax/2; -wMax/2, -hMax/2];
            %clockwise rotation matrix
            rMax = [cosd(-thetaMax) -sind(-thetaMax); sind(-thetaMax) cosd(-thetaMax)];
            %applying rotation to transpose
            rotMax = (rMax * corners')';
            %plot relative to centroid
            plot(rotMax(:,1) + xc, rotMax(:,2) + yc, 'y--', 'LineWidth', 1);

            wMin = allWidths(minIdx);
            hMin = allHeights(minIdx);
            corners_n = [-wMin/2, -hMin/2; wMin/2, -hMin/2; wMin/2, hMin/2; -wMin/2, hMin/2; -wMin/2, -hMin/2];
            rMin = [cosd(-thetaMin) -sind(-thetaMin); sind(-thetaMin) cosd(-thetaMin)];
            rotMin = (rMin * corners_n')';
            plot(rotMin(:,1) + xc, rotMin(:,2) + yc, 'cyan--', 'LineWidth', 1);

            %Approximate centre
            plot(xc, yc, 'r+', 'MarkerSize', 10, 'LineWidth', 2);
            hold off;

            if abs(calcDiameter - acrDiameter) <= 2
                resultStr = 'PASS';
            elseif abs(calcDiameter - acrDiameter) <= 3
                resultStr = 'MARGINAL';
            else
                resultStr = 'FAIL';
            end

            fprintf('--- Geometric Accuracy ---\n');
            fprintf('Equivalent Diameter: %.2f mm\nMajor Axis: %.2f mm\nMinor Axis: %.2f mm\n Geometric Distortion: %.3f %%\nResult: %s\n', calcDiameter, maxDiam, minDiam, distortion, resultStr);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @UIFigureWindowScrollWheel, true);
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.axis_kspace = uiaxes(app.UIFigure);
            app.axis_kspace.XTick = [];
            app.axis_kspace.YTick = [];
            app.axis_kspace.Tag = 'axis_kspace';
            app.axis_image = uiaxes(app.UIFigure);
            app.axis_image.XTick = [];
            app.axis_image.YTick = [];
            app.axis_image.ButtonDownFcn = createCallbackFcn(app, @axis_imageButtonDown, true);
            app.axis_image.Tag = 'axis_image';
            app.axis_coordinates = uiaxes(app.UIFigure);
            app.axis_coordinates.XTick = [];
            app.axis_coordinates.YTick = [];
            app.axis_coordinates.Tag = 'axis_image';
            app.panel_recon = uipanel(app.UIFigure);
            app.panel_recon.Title = 'Reconstruction Options';
            app.panel_recon.Tag = 'panel_recon';
            app.SizeofFFTDropDownLabel = uilabel(app.panel_recon);
            app.SizeofFFTDropDownLabel.Enable = 'off';
            app.SizeofFFTDropDownLabel.Text = 'Size of FFT';
            app.FFTDropDown = uidropdown(app.panel_recon);
            app.FFTDropDown.Items = {'64', '128', '256', '512'};
            app.FFTDropDown.ValueChangedFcn = createCallbackFcn(app, @FFTDropDownValueChanged, true);
            app.FFTDropDown.Enable = 'off';
            app.FFTDropDown.Tooltip = {'Size of the FFT. Larger sizes of transform are accomplished by symmetrically zero-padding kspace'};
            app.FFTDropDown.Value = '64';
            app.WindowDropDownLabel = uilabel(app.panel_recon);
            app.WindowDropDownLabel.Enable = 'off';
            app.WindowDropDownLabel.Text = 'Window';
            app.WindowDropDown = uidropdown(app.panel_recon);
            app.WindowDropDown.Items = {'None', 'Hann', 'Blackman', 'Hamming', 'Kaiser-Bessel', 'Tukey'};
            app.WindowDropDown.ValueChangedFcn = createCallbackFcn(app, @WindowDropDownValueChanged, true);
            app.WindowDropDown.Enable = 'off';
            app.WindowDropDown.Tooltip = {'kspace windowing function'};
            app.WindowDropDown.Value = 'None';
            app.WindowSizeEditFieldLabel = uilabel(app.panel_recon);
            app.WindowSizeEditFieldLabel.Enable = 'off';
            app.WindowSizeEditFieldLabel.Text = 'Window Size';
            app.WindowSizeEditField = uieditfield(app.panel_recon, 'numeric');
            app.WindowSizeEditField.Limits = [0 10];
            app.WindowSizeEditField.ValueChangedFcn = createCallbackFcn(app, @WindowSizeEditFieldValueChanged, true);
            app.WindowSizeEditField.Enable = 'off';
            app.WindowSizeEditField.Tooltip = {'Size of the windowing function as a fraction of the base image size'};
            app.WindowSizeEditField.Value = 1;
            app.FilterDropDownLabel = uilabel(app.panel_recon);
            app.FilterDropDownLabel.Enable = 'off';
            app.FilterDropDownLabel.Text = 'Filter';
            app.FilterDropDown = uidropdown(app.panel_recon);
            app.FilterDropDown.Items = {'None', 'Total Variation', 'Generalised Total Variation', 'Deep Learning'};
            app.FilterDropDown.ValueChangedFcn = createCallbackFcn(app, @FilterDropDownValueChanged, true);
            app.FilterDropDown.Enable = 'off';
            app.FilterDropDown.Tooltip = {'Denoising filter'};
            app.FilterDropDown.Value = 'None';
            app.RebuildImagesButton = uibutton(app.panel_recon, 'push');
            app.RebuildImagesButton.ButtonPushedFcn = createCallbackFcn(app, @RebuildImagesButtonPushed, true);
            app.RebuildImagesButton.Enable = 'off';
            app.RebuildImagesButton.Tooltip = {'Rebuilds images from phase-corrected k-space. Useful when testing new reconstruction methods'};
            app.RebuildImagesButton.Text = 'Rebuild Images';
            app.CombinationDropDownLabel = uilabel(app.panel_recon);
            app.CombinationDropDownLabel.Enable = 'off';
            app.CombinationDropDownLabel.Text = 'Combination';
            app.CombinationDropDown = uidropdown(app.panel_recon);
            app.CombinationDropDown.Items = {'RSSQ', 'Adaptive', 'ACS/Walsh'};
            app.CombinationDropDown.ValueChangedFcn = createCallbackFcn(app, @CombinationDropDownValueChanged, true);
            app.CombinationDropDown.Enable = 'off';
            app.CombinationDropDown.Tooltip = {'Algorithm for combining multiple receivers'};
            app.CombinationDropDown.Value = 'Adaptive';
            app.BiasFieldDropDownLabel = uilabel(app.panel_recon);
            app.BiasFieldDropDownLabel.Enable = 'off';
            app.BiasFieldDropDownLabel.Text = 'Bias Field';
            app.BiasFieldDropDown = uidropdown(app.panel_recon);
            app.BiasFieldDropDown.Items = {'Off', 'ACS'};
            app.BiasFieldDropDown.Enable = 'off';
            app.BiasFieldDropDown.Tooltip = {'Attempt to correct uniformity inhomogeneity due to coil sensitivities'};
            app.BiasFieldDropDown.Value = 'Off';
            app.CurrentImagePanel = uipanel(app.UIFigure);
            app.CurrentImagePanel.Title = 'Current Image';
            app.EvolutionFieldDropDownLabel = uilabel(app.CurrentImagePanel);
            app.EvolutionFieldDropDownLabel.Enable = 'off';
            app.EvolutionFieldDropDownLabel.Text = 'Evolution Field';
            app.EvolutionFieldDropDown = uidropdown(app.CurrentImagePanel);
            app.EvolutionFieldDropDown.Items = {'200'};
            app.EvolutionFieldDropDown.ValueChangedFcn = createCallbackFcn(app, @EvolutionFieldDropDownValueChanged, true);
            app.EvolutionFieldDropDown.Enable = 'off';
            app.EvolutionFieldDropDown.Tooltip = {'Evolution field in mT'};
            app.EvolutionFieldDropDown.Value = '200';
            app.EvolutionTimeDropDownLabel = uilabel(app.CurrentImagePanel);
            app.EvolutionTimeDropDownLabel.Enable = 'off';
            app.EvolutionTimeDropDownLabel.Text = 'Evolution Time';
            app.EvolutionTimeDropDown = uidropdown(app.CurrentImagePanel);
            app.EvolutionTimeDropDown.Items = {'0'};
            app.EvolutionTimeDropDown.ValueChangedFcn = createCallbackFcn(app, @EvolutionTimeDropDownValueChanged, true);
            app.EvolutionTimeDropDown.Enable = 'off';
            app.EvolutionTimeDropDown.Tooltip = {'Evolution time in ms'};
            app.EvolutionTimeDropDown.Value = '0';
            app.SliceDropDownLabel = uilabel(app.CurrentImagePanel);
            app.SliceDropDownLabel.Enable = 'off';
            app.SliceDropDownLabel.Text = 'Slice';
            app.SliceDropDown = uidropdown(app.CurrentImagePanel);
            app.SliceDropDown.Items = {'1'};
            app.SliceDropDown.ValueChangedFcn = createCallbackFcn(app, @SliceDropDownValueChanged, true);
            app.SliceDropDown.Enable = 'off';
            app.SliceDropDown.Tooltip = {'Slice index'};
            app.SliceDropDown.Value = '1';
            app.ImageTypeDropDownLabel = uilabel(app.CurrentImagePanel);
            app.ImageTypeDropDownLabel.Enable = 'off';
            app.ImageTypeDropDownLabel.Text = 'Image Type';
            app.ImageTypeDropDown = uidropdown(app.CurrentImagePanel);
            app.ImageTypeDropDown.Items = {'Magnitude', 'Phase', 'Relaxometry Map'};
            app.ImageTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ImageTypeDropDownValueChanged, true);
            app.ImageTypeDropDown.Enable = 'off';
            app.ImageTypeDropDown.Tooltip = {'Displayed image can be the magnitude image, a phase map or a T1 or R1 parameter map'};
            app.ImageTypeDropDown.Value = 'Magnitude';
            app.RegisterImagesButton = uibutton(app.CurrentImagePanel, 'push');
            app.RegisterImagesButton.ButtonPushedFcn = createCallbackFcn(app, @RegisterImagesButtonPushed, true);
            app.RegisterImagesButton.Enable = 'off';
            app.RegisterImagesButton.Text = 'Register Images';
            app.DisplayOptionsPanel = uipanel(app.UIFigure);
            app.DisplayOptionsPanel.Title = 'Display Options';
            app.WindowLevelSliderLabel = uilabel(app.DisplayOptionsPanel);
            app.WindowLevelSliderLabel.Enable = 'off';
            app.WindowLevelSliderLabel.Text = 'Window Level';
            app.WindowLevelSlider = uislider(app.DisplayOptionsPanel);
            app.WindowLevelSlider.Limits = [-1 1];
            app.WindowLevelSlider.MajorTicks = [];
            app.WindowLevelSlider.MajorTickLabels = {''};
            app.WindowLevelSlider.ValueChangedFcn = createCallbackFcn(app, @WindowLevelSliderValueChanged, true);
            app.WindowLevelSlider.MinorTicks = [-1 -0.9 -0.8 -0.7 -0.6 -0.5 -0.4 -0.3 -0.2 -0.1 0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.WindowLevelSlider.Enable = 'off';
            app.WindowWidthSliderLabel = uilabel(app.DisplayOptionsPanel);
            app.WindowWidthSliderLabel.Enable = 'off';
            app.WindowWidthSliderLabel.Text = 'Window Width';
            app.WindowWidthSlider = uislider(app.DisplayOptionsPanel);
            app.WindowWidthSlider.Limits = [0 1];
            app.WindowWidthSlider.MajorTicks = [];
            app.WindowWidthSlider.MajorTickLabels = {''};
            app.WindowWidthSlider.ValueChangedFcn = createCallbackFcn(app, @WindowWidthSliderValueChanged, true);
            app.WindowWidthSlider.MinorTicks = [0 0.05 0.1 0.15 0.2 0.25 0.3 0.35 0.4 0.45 0.5 0.55 0.6 0.65 0.7 0.75 0.8 0.85 0.9 0.95 1];
            app.WindowWidthSlider.Enable = 'off';
            app.WindowWidthSlider.Value = 1;
            app.RotateImageButton = uibutton(app.DisplayOptionsPanel, 'push');
            app.RotateImageButton.ButtonPushedFcn = createCallbackFcn(app, @RotateImageButtonPushed, true);
            app.RotateImageButton.Enable = 'off';
            app.RotateImageButton.Text = 'Rotate Image';
            app.FlipHorizontallyButton = uibutton(app.DisplayOptionsPanel, 'push');
            app.FlipHorizontallyButton.ButtonPushedFcn = createCallbackFcn(app, @FlipHorizontallyButtonPushed, true);
            app.FlipHorizontallyButton.Enable = 'off';
            app.FlipHorizontallyButton.Text = 'Flip Horizontally';
            app.FlipVerticallyButton = uibutton(app.DisplayOptionsPanel, 'push');
            app.FlipVerticallyButton.ButtonPushedFcn = createCallbackFcn(app, @FlipVerticallyButtonPushed, true);
            app.FlipVerticallyButton.Enable = 'off';
            app.FlipVerticallyButton.Text = 'Flip Vertically';
            app.IndividualNormalisationButton = uibutton(app.DisplayOptionsPanel, 'state');
            app.IndividualNormalisationButton.ValueChangedFcn = createCallbackFcn(app, @IndividualNormalisationButtonValueChanged, true);
            app.IndividualNormalisationButton.Tooltip = {'Displayed images can either be individually normalised (using imagesc) or else scaled to the maximum intensity of the first image in the data set (usually an image acquired at 200 mt)'};
            app.IndividualNormalisationButton.Enable = 'off';
            app.IndividualNormalisationButton.Text = 'Individual Normalisation';
            app.ScanListPanel = uipanel(app.UIFigure);
            app.ScanListPanel.Title = 'Scan List';
            app.ScanList = uilistbox(app.ScanListPanel);
            app.ScanList.Items = {'Scans will appear here'};
            app.ScanList.ValueChangedFcn = createCallbackFcn(app, @ScanListValueChanged, true);
            app.ScanList.Value = 'Scans will appear here';
            app.RelaxometryPanel = uipanel(app.UIFigure);
            app.RelaxometryPanel.Title = 'Relaxometry';
            app.GenerateRelaxationMapsButton = uibutton(app.RelaxometryPanel, 'push');
            app.GenerateRelaxationMapsButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateRelaxationMapsButtonPushed, true);
            app.GenerateRelaxationMapsButton.Enable = 'off';
            app.GenerateRelaxationMapsButton.Tooltip = {'Produces voxel-wise relaxation parameter maps. See MapRelaxation for details of how the maps are produced'};
            app.GenerateRelaxationMapsButton.Text = 'Generate Relaxation Maps';
            app.GenerateROIDispersionCurveButton = uibutton(app.RelaxometryPanel, 'push');
            app.GenerateROIDispersionCurveButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateROIDispersionCurveButtonPushed, true);
            app.GenerateROIDispersionCurveButton.Enable = 'off';
            app.GenerateROIDispersionCurveButton.Tooltip = {'Produces a ROI based dispersion curve. See T1dispersion for details on how the results are derived.'};
            app.GenerateROIDispersionCurveButton.Text = 'Generate ROI Dispersion Curve';
            app.CheckfitsButton = uibutton(app.RelaxometryPanel, 'state');
            app.CheckfitsButton.Tooltip = {'Displays the relaxation fits when either relaxation maps or ROI dispersion curves are generated'};
            app.CheckfitsButton.Enable = 'off';
            app.CheckfitsButton.Text = 'Check fits';
            app.LogarithmicMapsButton = uibutton(app.RelaxometryPanel, 'state');
            app.LogarithmicMapsButton.ValueChangedFcn = createCallbackFcn(app, @LogarithmicMapsButtonValueChanged, true);
            app.LogarithmicMapsButton.Tooltip = {'Visualises relaxation maps either linearly or in log scale. Log scale can be useful for comparing relaxation between different magnetic fields where there is a large change in T1 values'};
            app.LogarithmicMapsButton.Enable = 'off';
            app.LogarithmicMapsButton.Text = 'Logarithmic Maps';
            app.DisplayingasT1Button = uibutton(app.RelaxometryPanel, 'state');
            app.DisplayingasT1Button.ValueChangedFcn = createCallbackFcn(app, @DisplayingasT1ButtonValueChanged, true);
            app.DisplayingasT1Button.Tooltip = {'Displays dispersion curves or relaxation maps in terms of R1 or T1'};
            app.DisplayingasT1Button.Enable = 'off';
            app.DisplayingasT1Button.Text = 'Displaying as T1';
            app.ColourAxisUpperLimitEditFieldLabel = uilabel(app.RelaxometryPanel);
            app.ColourAxisUpperLimitEditFieldLabel.Enable = 'off';
            app.ColourAxisUpperLimitEditFieldLabel.Text = 'Colour Axis Upper Limit';
            app.ColourAxisUpperLimitEditField = uieditfield(app.RelaxometryPanel, 'numeric');
            app.ColourAxisUpperLimitEditField.Limits = [0 Inf];
            app.ColourAxisUpperLimitEditField.ValueChangedFcn = createCallbackFcn(app, @ColourAxisUpperLimitEditFieldValueChanged, true);
            app.ColourAxisUpperLimitEditField.Enable = 'off';
            app.ColourAxisUpperLimitEditField.Tooltip = {'Upper limit of displayed T1/R1 values in relaxation maps'};
            app.ColourAxisUpperLimitEditField.Value = 1000;
            app.ColourAxisLowerLimitEditFieldLabel = uilabel(app.RelaxometryPanel);
            app.ColourAxisLowerLimitEditFieldLabel.Enable = 'off';
            app.ColourAxisLowerLimitEditFieldLabel.Text = 'Colour Axis Lower Limit';
            app.ColourAxisLowerLimitEditField = uieditfield(app.RelaxometryPanel, 'numeric');
            app.ColourAxisLowerLimitEditField.Limits = [0 Inf];
            app.ColourAxisLowerLimitEditField.ValueChangedFcn = createCallbackFcn(app, @ColourAxisLowerLimitEditFieldValueChanged, true);
            app.ColourAxisLowerLimitEditField.Enable = 'off';
            app.ColourAxisLowerLimitEditField.Tooltip = {'Lower limit of displayed T1/R1 values in relaxation maps'};
            app.T2Button = uibutton(app.RelaxometryPanel, 'push');
            app.T2Button.ButtonPushedFcn = createCallbackFcn(app, @T2ButtonPushed, true);
            app.T2Button.Text = 'T2';
            app.Panel_2 = uipanel(app.UIFigure);
            app.LoadDataSetButton = uibutton(app.Panel_2, 'push');
            app.LoadDataSetButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataSetButtonPushed, true);
            app.LoadDataSetButton.Tooltip = {'Open a FFC data set. '};
            app.LoadDataSetButton.Text = 'Load Data Set';
            app.ReprocessingButton = uibutton(app.Panel_2, 'state');
            app.ReprocessingButton.Tooltip = {'Forces the complete reconstruction of the data set. Otherwise the software will look for preprocessed data and open that instead. Can be time consuming for large data sets.'};
            app.ReprocessingButton.Text = 'Reprocessing';
            app.BackgroundselectionButton = uibutton(app.Panel_2, 'state');
            app.BackgroundselectionButton.Tooltip = {'Allows the user to select the image background to aid in image reconstruction. Usually not needed but may be useful if significant ghosting remains.'};
            app.BackgroundselectionButton.Text = 'Background selection';
            app.ExportOptionsPanel = uipanel(app.UIFigure);
            app.ExportOptionsPanel.Title = 'Export Options';
            app.ExportscantoworkspaceButton = uibutton(app.ExportOptionsPanel, 'push');
            app.ExportscantoworkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @ExportscantoworkspaceButtonPushed, true);
            app.ExportscantoworkspaceButton.Enable = 'off';
            app.ExportscantoworkspaceButton.Tooltip = {'Export the scan object to the MATLAB workspace'};
            app.ExportscantoworkspaceButton.Text = 'Export scan to workspace';
            app.ExportallimagesButton = uibutton(app.ExportOptionsPanel, 'push');
            app.ExportallimagesButton.ButtonPushedFcn = createCallbackFcn(app, @ExportallimagesButtonPushed, true);
            app.ExportallimagesButton.Enable = 'off';
            app.ExportallimagesButton.Tooltip = {'Export the current data set (magnitude, phase or relaxation map) as a series of .tif images.'};
            app.ExportallimagesButton.Text = 'Export all images';
            app.ExportstackedimagesButton = uibutton(app.ExportOptionsPanel, 'push');
            app.ExportstackedimagesButton.ButtonPushedFcn = createCallbackFcn(app, @ExportstackedimagesButtonPushed, true);
            app.ExportstackedimagesButton.Enable = 'off';
            app.ExportstackedimagesButton.Tooltip = {'Export the current data set (magnitude, phase or relaxation map) as a stacked .tif image. For magnitude and phase images a stacked image of evolution times will be generated for each evolution field. For relaxation maps a single stack containing all relaxation maps will be generated.'};
            app.ExportstackedimagesButton.Text = 'Export stacked images';
            app.ExportscanparameterstoworkspaceButton = uibutton(app.ExportOptionsPanel, 'push');
            app.ExportscanparameterstoworkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @ExportscanparameterstoworkspaceButtonPushed, true);
            app.ExportscanparameterstoworkspaceButton.Enable = 'off';
            app.ExportscanparameterstoworkspaceButton.Tooltip = {'Write the current scan parameters in the workspace'};
            app.ExportscanparameterstoworkspaceButton.Text = 'Export scan parameters to workspace';
            app.CreateFigurePanel = uipanel(app.ExportOptionsPanel);
            app.CreateFigurePanel.Title = 'Create Figure';
            app.kspaceButton = uibutton(app.CreateFigurePanel, 'push');
            app.kspaceButton.ButtonPushedFcn = createCallbackFcn(app, @kspaceButtonPushed, true);
            app.kspaceButton.Enable = 'off';
            app.kspaceButton.Tooltip = {'Produce a new figure containing the currently displayed k-space matrix.'};
            app.kspaceButton.Text = 'k-space';
            app.ImageButton = uibutton(app.CreateFigurePanel, 'push');
            app.ImageButton.ButtonPushedFcn = createCallbackFcn(app, @ImageButtonPushed, true);
            app.ImageButton.Enable = 'off';
            app.ImageButton.Tooltip = {'Produce a new figure containing the currently displayed image.'};
            app.ImageButton.Text = 'Image';
            app.MultichannelDropDown = uilistbox(app.UIFigure);
            app.MultichannelDropDown.Items = {};
            app.MultichannelDropDown.Multiselect = 'on';
            app.MultichannelDropDown.ValueChangedFcn = createCallbackFcn(app, @MultichannelDropDownValueChanged2, true);
            app.MultichannelDropDown.Enable = 'off';
            app.MultichannelDropDown.Tooltip = {'Multichannel reconstruction options. Currently supports sum of squares and channel averaging. Individual channels can also be inspected.'};
            app.MultichannelDropDown.Value = {};
            app.QualityAssurancePanel = uipanel(app.UIFigure);
            app.QualityAssurancePanel.Title = 'Quality Assurance';
            app.GeometricAccButton = uibutton(app.QualityAssurancePanel, 'push');
            app.GeometricAccButton.ButtonPushedFcn = createCallbackFcn(app, @GeometricAccButtonPushed, true);
            app.GeometricAccButton.Text = 'Geometric Acc';
            app.UniformityButton = uibutton(app.QualityAssurancePanel, 'push');
            app.UniformityButton.ButtonPushedFcn = createCallbackFcn(app, @UniformityButtonPushed, true);
            app.UniformityButton.Text = 'Uniformity';
            app.SNRButton = uibutton(app.QualityAssurancePanel, 'push');
            app.SNRButton.ButtonPushedFcn = createCallbackFcn(app, @SNRButtonPushed, true);
            app.SNRButton.Enable = 'off';
            app.SNRButton.Tooltip = {'Attempts to determine the overall image SNR. This method is better suited to QA style measurements with phantoms rather than in-vivo experiments.'};
            app.SNRButton.Text = 'SNR';
            app.THKButton = uibutton(app.QualityAssurancePanel, 'push');
            app.THKButton.ButtonPushedFcn = createCallbackFcn(app, @THKButtonPushed, true);
            app.THKButton.Text = 'THK';
            app.AllButton = uibutton(app.QualityAssurancePanel, 'push');
            app.AllButton.ButtonPushedFcn = createCallbackFcn(app, @AllButtonPushed, true);
            app.AllButton.Text = 'All';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FFCProcessing

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end